classdef classiRepository < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifierRepositoryUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SetrepositorypathMenu         matlab.ui.container.Menu
        Button                        matlab.ui.control.Button
        ListofavailableclassifiersPanel  matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        ClassifierpropertiesPanel     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              if numel(userpath)==0
                 userpath('reset')
             end

            pth=userpath;
 
            if ~exist(fullfile(pth,'Detecdiv'))
                mkdir(fullfile(pth,'Detecdiv'));
            end

           fle= fullfile(pth,'Detecdiv/repositoryPath.mat');

            if exist(fle)
                load(fle);
            else
                repopth=fullfile(pth, 'ClassiRepository');

                if ~exist(repopth)
                       mkdir(repopth)
                end

                save(fle,'repopth')
            end

      
        app.Data.repopth=repopth;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            detecdiv
        end

        % Menu selected function: SetrepositorypathMenu
        function SetrepositorypathMenuSelected(app, event)
           
            
            repopth=uigetdir(app.Data.repopth,'Input the location of the Classi Repostiory');

            app.Data.repopth=repopth;

            pth=userpath;
             fle= fullfile(pth,'Detecdiv/repositoryPath.mat');

             save(fle,'repopth');

             disp(['User selected : ' repopth ' for the classi repository']);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifierRepositoryUIFigure = uifigure('Visible', 'off');
            app.ClassifierRepositoryUIFigure.Name = 'Classifier Repository';
            app.FileMenu = uimenu(app.ClassifierRepositoryUIFigure);
            app.FileMenu.Text = 'File';
            app.SetrepositorypathMenu = uimenu(app.FileMenu);
            app.SetrepositorypathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetrepositorypathMenuSelected, true);
            app.SetrepositorypathMenu.Text = 'Set repository path...';
            app.ClassifierpropertiesPanel = uipanel(app.ClassifierRepositoryUIFigure);
            app.ClassifierpropertiesPanel.Title = 'Classifier properties';
            app.ListofavailableclassifiersPanel = uipanel(app.ClassifierRepositoryUIFigure);
            app.ListofavailableclassifiersPanel.Title = 'List of available classifiers';
            app.UITable = uitable(app.ListofavailableclassifiersPanel);
            app.UITable.ColumnName = {'Name'; 'Status'; 'Column 3'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.ClassifierRepositoryUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.ClassifierRepositoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classiRepository

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifierRepositoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifierRepositoryUIFigure)
        end
    end
end