classdef classifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveclassifierandparametersMenu  matlab.ui.container.Menu
        ClassifierMenu                  matlab.ui.container.Menu
        FormattrainingsetMenu           matlab.ui.container.Menu
        TrainClassifierMenu             matlab.ui.container.Menu
        ValidateclassifierusingtestsetMenu  matlab.ui.container.Menu
        DisplaystatisticsMenu           matlab.ui.container.Menu
        LoadclassifierMenu              matlab.ui.container.Menu
        CheckstatusMenu                 matlab.ui.container.Menu
        ClassifierisloadedinworksapcememoryLabel  matlab.ui.control.Label
        ParametersaresavedLabel         matlab.ui.control.Label
        StatusLoad                      matlab.ui.control.Lamp
        StatusSaved                     matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        ClassifierparametersTab         matlab.ui.container.Tab
        DeSelectButton                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        ChannelListBoxSel               matlab.ui.control.ListBox
        SelectedchannelsasclassificationinputLabel  matlab.ui.control.Label
        ChannelListBox                  matlab.ui.control.ListBox
        AvailablechannelstakenfromtrainingdatasetROIsListBoxLabel  matlab.ui.control.Label
        PostprocessingparametersPanel   matlab.ui.container.Panel
        SizepixelEditField              matlab.ui.control.NumericEditField
        SizepixelEditFieldLabel         matlab.ui.control.Label
        FilteroutsmallobjectsCheckBox   matlab.ui.control.CheckBox
        ThresholdingButtonGroup         matlab.ui.container.ButtonGroup
        ValueEditField                  matlab.ui.control.EditField
        ValueEditFieldLabel             matlab.ui.control.Label
        MaximumprobabilityButton        matlab.ui.control.RadioButton
        AdaptivethresholdOtsumethodButton  matlab.ui.control.RadioButton
        FixedthresholdButton            matlab.ui.control.RadioButton
        WatershedCheckBox               matlab.ui.control.CheckBox
        PostprocessingDropDown          matlab.ui.control.DropDown
        PostprocessingDropDownLabel     matlab.ui.control.Label
        ClassiDetailsLabel              matlab.ui.control.Label
        PostprocessingcustomfunctionhandleEditField  matlab.ui.control.EditField
        PostprocessingcustomfunctionhandleEditFieldLabel  matlab.ui.control.Label
        SpaceseparatedclassnamesEditField  matlab.ui.control.EditField
        SpaceseparatedclassnamesLabel   matlab.ui.control.Label
        ClassifieroutputtypeDropDown    matlab.ui.control.DropDown
        ClassifieroutputtypeDropDownLabel  matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        TrainingprocedureparametersTab  matlab.ui.container.Tab
        UITrainingTable                 matlab.ui.control.Table
        DatasetsROIsTab                 matlab.ui.container.Tab
        removeselectedROIButton         matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        AnnotateselectedROIButton       matlab.ui.control.Button
        ImportROIsButton                matlab.ui.control.Button
        UITableData                     matlab.ui.control.Table
    end


    properties (Access = private)
        callingApp % Description
    end

    properties (Access = public)
        Data % Description % must be public to shar info with other apps
    end

    methods (Access = private)

        function  displayClassi(app) % displays the data from classi as they are

            % classi type
            typelist=app.Data.classlist{:,2};
            classiObj=app.Data.classiObj;

            if numel(classiObj.description)==0 % new classi 
                pix=1;
                classlist=app.Data.classlist;

            app.Data.classiObj.description={classlist{pix,2} ' - ' classlist{pix,3}};
            app.Data.classiObj.category=classlist{pix,4};
            app.Data.classiObj.classifyFun=classlist{pix,6}{1};
            app.Data.classiObj.trainingFun=classlist{pix,5}{1};
            end
            
            if numel(find(matches(app.TypeDropDown.Items,app.Data.classiObj.description{1})))
           app.TypeDropDown.Value=app.Data.classiObj.description{1};
            else
                app.TypeDropDown.Items=[app.TypeDropDown.Items app.Data.classiObj.description{1}];
                app.TypeDropDown.Value=app.Data.classiObj.description{1};
            end

            % classi description
           % if numel(classiObj.description)
            app.ClassiDetailsLabel.Text=classiObj.description{3};
           % end

            
            app.PostprocessingparametersPanel.Enable='off';
            app.PostprocessingDropDown.Enable='off';
            app.PostprocessingcustomfunctionhandleEditField.Enable='off';

            % output
            if strcmp(classiObj.category{1},'LSTM')|| strcmp(classiObj.category{1},'Timeseries') % timeseries classifiers
                app.ClassifieroutputtypeDropDown.Enable='on';

                app.ClassifieroutputtypeDropDown.Items={'Sequence-to-sequence' 'Sequence-to-one'};
                app.ClassifieroutputtypeDropDown.Value= app.ClassifieroutputtypeDropDown.Items(classiObj.output+1);
            else
                app.ClassifieroutputtypeDropDown.Items={};
                app.ClassifieroutputtypeDropDown.Enable='off' ;
            end

            % channels


            app.ChannelListBoxSel.Items={};
            app.ChannelListBoxSel.Enable='off';
            
            cha={};
            
            for i=1:numel(classiObj.roi)
                if numel(classiObj.roi(i).id)
                    tmp=classiObj.roi(i).display.channel(:);
                    if size(tmp,1)>1
                        tmp=tmp';
                    end

                    cha=unique([cha tmp],'stable');

                end
            end
            
            if numel(cha)
            app.ChannelListBox.Items=cha;
            app.ChannelListBox.Enable='on';
            else
            app.ChannelListBox.Items={};
            app.ChannelListBox.Enable='off';    
            end
            
            if numel(classiObj.channelName)==0 % in case previou system is used
                if numel(cha) & numel(classiObj.channel)
                    if numel(cha)>= classiObj.channel(1)
                        channelName=cha(classiObj.channel(1));
                        classiObj.channelName=channelName;
                        app.ChannelListBoxSel.Items=channelName;
                        app.ChannelListBoxSel.Enable='on';
                    end
                else
                    channelName='';
                end
            else
                channelName=classiObj.channelName;
                
                if ischar(channelName) % fix previous classification format
                    channelName={channelName};
                    classiObj.channelName=channelName; 
                end
                
                app.ChannelListBoxSel.Items=channelName;
                app.ChannelListBoxSel.Enable='on';
            end
            

            % classes
            %   aaa=classiObj.description{1}
            if strcmp(classiObj.description{1},'Deep Image Regression') || strcmp(classiObj.description{1},'Deep image sequence regression') % image regression : no classes needed
                classiObj.classes={};
                %   app.SpaceseparatedclassnamesEditField
                app.SpaceseparatedclassnamesEditField.Enable='off';

            else
                app.SpaceseparatedclassnamesEditField.Enable='on';
            end

            if numel(classiObj.classes)
                str='';
                for i=1:numel(classiObj.classes)
                    str=[str classiObj.classes{i} ' '];
                end
                app.SpaceseparatedclassnamesEditField.Value=str;
            else
                app.SpaceseparatedclassnamesEditField.Value='';
            end

            % postprocessing functions
            outputstr='';
            % outut functions


            if strcmp(classiObj.category{1},'Pixel')
                %   aa=classiObj.outputFun
                %   class(aa)
                app.PostprocessingDropDown.Enable='on';
                if numel(classiObj.outputType)
                    outputstr=classiObj.outputType;
                else
                    outputstr='proba';
                end
                %  outputstr

                switch outputstr
                    case 'proba'
                        outputstr='plain output / probabilities for each class';
                    case 'segmentation'
                        outputstr='plain output / semantic segmentation';
                    case 'postprocessing'
                            if ischar(classiObj.outputFun)
                                if strcmp(classiObj.outputFun,'post')
                                    outputstr='postprocessing';
                                else
                                    outputstr='custom function (see below)';
                                end
                            end
                end
                        
                app.PostprocessingDropDown.Value=outputstr;


                        if strcmp(outputstr,'custom function (see below)')
                            app.PostprocessingcustomfunctionhandleEditField.Enable='on';

                            if  numel(classiObj.outputFun)
                                if ischar(classiObj.outputFun)
                                    app.PostprocessingcustomfunctionhandleEditField.Value=classiObj.outputFun;
                                end
                            end
                        else
                            app.PostprocessingcustomfunctionhandleEditField.Enable='off';
                            app.PostprocessingcustomfunctionhandleEditField.Value='';
                        end

                            outputArg=classiObj.outputArg;
                            app.WatershedCheckBox.Value=false;
                            app.FilteroutsmallobjectsCheckBox.Value=false;
                            app.SizepixelEditField.Value=10;
                            app.FixedthresholdButton.Value=false;
                            app.ValueEditField.Value=num2str(0.9);
                            app.AdaptivethresholdOtsumethodButton.Value=false;
                            app.MaximumprobabilityButton.Value=false;
                            
                        if  strcmp(outputstr,'postprocessing')

                            app.PostprocessingcustomfunctionhandleEditField.Value='post';
                            app.PostprocessingparametersPanel.Enable='on';
                            app.PostprocessingcustomfunctionhandleEditField.Value='post';

                            % display postprocessing parameters
                            
                            
                            for j=1:numel(outputArg)

                                if strcmp(outputArg{j},'watershed')
                                    app.WatershedCheckBox.Value=true;
                                end

                                if strcmp(outputArg{j},'sizethreshold')
                                    app.FilteroutsmallobjectsCheckBox.Value=true;
                                    app.SizepixelEditField.Value=str2double(outputArg{j+1});
                                end
                                if strcmp(outputArg{j},'threshold')
                                    app.FixedthresholdButton.Value=true;

                                    app.ValueEditField.Value=num2str(outputArg{j+1});
                                end
                                if strcmp(outputArg{j},'adaptivethreshold')
                                    app.AdaptivethresholdOtsumethodButton.Value=true;

                                end
                                if strcmp(outputArg{j},'maxproba')
                                    app.MaximumprobabilityButton.Value=true;
                                end
                            end


                        end
                    else
                        app.PostprocessingDropDown.Enable='off';
                end


                % HERE
            end

            function  displayTraining(app)
                % displays training parameters as an editable table

                shortParam=app.Data.trainingParam;

                excludestr=fieldnames(shortParam);

                if numel(app.Data.trainingParam.imageclassifier)==0
                    excludestr=excludestr(contains(excludestr,{'freeze','rois','imageclassifier','roisID','classes'}));
                else
                    excludestr=excludestr(contains(excludestr,{'freeze','rois','roisID','classes'}));
                end
                shortParam=rmfield(shortParam,excludestr);

                app.Data.shortParam=shortParam;

                % dat=struct2table(shortParam,'AsArray',true)
                fi=fieldnames(shortParam);
                Data={};
                for i=1:numel(fi)
                    tmp=num2str(shortParam.(fi{i}));
                    Data=[Data tmp];
                    %   Data=[Data i];
                end

                t=app.UITrainingTable;

                t.Data=Data;
                t.ColumnName=fi;
                t.ColumnEditable=true;

                category=app.Data.classiObj.category{1};

                switch category
                    case 'LSTM' % lstm+cnn image sequence
                        t.ColumnFormat={{'y','n'} {'y','n'} {'y','n'} {'y','n'} []  {'adam' 'sgdm'} {'resnet18' 'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'} [] []  [] []};
                    case 'Pixel' % pixel classification
                        
                        if app.Data.classiObj.description{1}=="Semantic segmentation (U-Net)"
                            t.Data{:,2}='unet';
                            t.ColumnFormat={{'adam' 'sgdm'} {'unet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'}};
                        else
                        t.ColumnFormat={{'adam' 'sgdm'} {'resnet18' 'resnet50' 'googlenet' 'unet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'}};
                        end
                        
                    case 'Timeseries'
                        %  t.ColumnFormat={[] {'adam' 'sgdm'} {'resnet18' 'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'}};
                end
            end

            function displayData(app)
                rois=app.Data.classiObj.roi;
                trainingrois=app.Data.trainingParam.rois;

                % formatting roi table to be displayed :
                % columns :  selected for training , select for test, roi index, roi id ,
                % isannotated, isvalidated

                t=app.UITableData;

                Data={};

                if numel(rois)==1
                    if numel(rois.id)==0
                        Data={};
                        t.Data={};
                        return;
                        % there is no roi available
                    end
                end

                for i=1:numel(rois)

                    istrainee=false;
                    istest=true;
                    isannotated=false;
                    isvalidated=false;
                    %  pix=find(trainingrois==i);
                    if numel(find(trainingrois==i))>0

                        istrainee=true;
                        istest=false;
                    end


                    category=app.Data.classiObj.category{1};
                    if strcmp(category,'Pixel') % pixel classification
                        % contains(rois(i).display.channel==app.Data.classiObj.strid)

                        t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};
                        t.ColumnWidth={150, 150, 100, 'auto' };
                        t.ColumnEditable=[true true false false];
                        Data(i,:)={istrainee, istest, i, rois(i).id};

                    else % all other classification

                        if isfield(rois(i).train,app.Data.classiObj.strid)
                            if numel(rois(i).train.(app.Data.classiObj.strid).id)
                                isannotated=true;
                            end
                        end
                        if isfield(rois(i).results,app.Data.classiObj.strid)
                            % 'ok'

                            if numel(rois(i).results.(app.Data.classiObj.strid).prob)
                                isvalidated=true;
                            end
                        end


                        t.ColumnName={'Select for training','Select for testing',' ROI index','ROI Id','is annotated','is validated'};
                        t.ColumnWidth={150, 150, 100, 'auto', 100, 100 };
                        t.ColumnEditable=[true true false false false false];
                        Data(i,:)={istrainee, istest, i, rois(i).id, isannotated, isvalidated};
                    end

                end


                t.Data=Data;


            end
        
            function  checkStatus(app,saving)
            % checks whether classi parameters are saved and classifier is
            % in memory
            
            classiObj=app.Data.classiObj;
            
            [~, check]=classiObj.loadClassifier('check');
            
%             varlist=evalin('base','who');
%             app.StatusLoad.Color=[1 0 0];
%             
%             for i=1:numel(varlist)
% 
%                 if strcmp(varlist{i},'ans')
%                     continue;
%                 end
% 
%                 tmp=evalin('base',varlist{i});

                if check
                   app.StatusLoad.Color=[0 1 0];
                    
                end
         %   end
           
            % checks whether paramas have changed
            if nargin==2
          
               if saving==false
                   app.StatusSaved.Color=[1 0 0];
               else
                   app.StatusSaved.Color=[0 1 0];
               end
            end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
            % HERE
            % app.callingApp=callingApp;
            % displayNodes(callingApp)
            %  gatherVarsFromWorkspace(callingApp)


            if nargin==1
                classiObj=classi;
                classiObj.path='';
                classiObj.strid='No input classi was provided';

            end

            app.UIFigure.Name=[classiObj.strid];
            app.Data.classiObj=classiObj;

            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/classification/classlist.mat'];
            load(str);
            app.Data.classlist=classlist;
            app.TypeDropDown.Items=classlist{:,2};
            app.Data.selected=[];

            if nargin>1
                displayClassi(app);
            end


            % loading training parameters
            if isfile([classiObj.path '/trainingParam.mat'])
                load([classiObj.path '/trainingParam.mat']) % loading trainingParam variable

                % disp(trainingParam);

            else

                disp('Training parameters do not exist: setting up defaults values!');

                switch classiObj.category{1}
                    case 'Pixel'
                        trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),'method','adam','network','resnet50',...
                            'MiniBatchSize',8,'MaxEpochs',100,'InitialLearnRate',0.001,'Shuffle','every-epoch','split',0.9,...
                            'translateAugmentation',[-7 7],'rotateAugmentation',[-20 20],'regularization',0.005,'ExecutionEnvironment','gpu');
                    case 'LSTM'
                        trainingParam=struct('imageclassifier','y','cactivations','y','lstmtraining','y','assemblenet','y','output',0,'rois',1:numel(classiObj.roi),'method','adam','network','googlenet',...
                            'MiniBatchSize',128,'MaxEpochs',6,'InitialLearnRate',0.0003,'Shuffle','every-epoch','split',0.7,...
                            'translateAugmentation',[-5 5],'rotateAugmentation',[-20 20],'regularization',0.00001,'ExecutionEnvironment','parallel',...
                            'lstmsplit',0.9,'lstmlayers',150,'lstmMiniBatchSize',8,'lstmInitialLearnRate', 0.0001);

                    case 'Timeseries'
                        trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),...
                            'lstmMiniBatchSize',8,'lstmInitialLearnRate',0.0001,'classes',classiObj.classes,'lstmsplit',0.7,'lstmlayers',150,'output',0);

                    otherwise
                        trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),'method','adam','network','resnet50',...
                            'MiniBatchSize',8,'MaxEpochs',100,'InitialLearnRate',0.001,'Shuffle','every-epoch','split',0.9,...
                            'translateAugmentation',[-7 7],'rotateAugmentation',[-20 20],'regularization',0.005,'ExecutionEnvironment','gpu');
                end

            end

            app.Data.trainingParam=trainingParam;

            checkStatus(app,true)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function
        function CheckstatusButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function SaveclassifierparametersButtonPushed(app, event)
            disp('Saving classifiers')

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier and trainingset...');
            d.Value=0.33;

            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';

            %a=app.Data.classiObj.path

            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.trainingParam.rois=find(selectedfortraining');
            else
                app.Data.trainingParam.rois =1:numel(app.Data.classiObj.roi);
            end


            if isfolder(app.Data.classiObj.path)
                mkdir([app.Data.classiObj.path '/TrainingValidation'])


                trainingParam=app.Data.trainingParam;
                save([app.Data.classiObj.path '/trainingParam.mat'],'trainingParam')
                save([app.Data.classiObj.path '/TrainingValidation' '/trainingParam.mat'],'trainingParam')

                d.Value=1;
                d.Message='Classifier is saved !';
            else
                d.Value=1;
                d.Message='Could not find/access the folder to save parameters; Check your connection? Quitting!';
                pause(1);
            end
            pause(0.5);
            close(d)
           
        end

        % Button down function: TrainingprocedureparametersTab
        function TrainingprocedureparametersTabButtonDown(app, event)
            displayTraining(app)

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            pix=find(contains(app.TypeDropDown.Items,value));
            app.Data.classiObj.typeid=pix;

            classlist=app.Data.classlist;

            app.Data.classiObj.description={classlist{pix,2} ' - ' classlist{pix,3}};
            app.Data.classiObj.category=classlist{pix,4};
            app.Data.classiObj.classifyFun=classlist{pix,6}{1};
            app.Data.classiObj.trainingFun=classlist{pix,5}{1};

            % aa=classlist{pix,3}
            checkStatus(app,false)
            displayClassi(app);
        end

        % Value changed function: ClassifieroutputtypeDropDown
        function ClassifieroutputtypeDropDownValueChanged(app, event)
            value = app.ClassifieroutputtypeDropDown.Value;
            pix=find(contains(app.ClassifieroutputtypeDropDown.Items,value));

            app.Data.classiObj.output=pix-1;


            app.Data.trainingParam.output=pix-1;
            checkStatus(app,false)
            displayClassi(app);
        end

        % Callback function
        function ChannelnameusedasinputEditFieldValueChanged(app, event)
            value = app.ChannelnameusedasinputEditField.Value;
            app.Data.classiObj.channel=str2num(value);
            checkStatus(app,false)
        end

        % Value changed function: SpaceseparatedclassnamesEditField
        function SpaceseparatedclassnamesEditFieldValueChanged(app, event)
            value = app.SpaceseparatedclassnamesEditField.Value;
            classes = textscan(value,'%s','Delimiter',' ');
            app.Data.classiObj.classes=classes{1};
            app.Data.classiObj.colormap=shallowColormap(numel(classes{1}));
            
            for i=1:numel(app.Data.classiObj.roi)
                
                app.Data.classiObj.roi(i).classes= app.Data.classiObj.classes;
            end
            checkStatus(app,false)
        end

        % Callback function
        function PostprocessingfunctionhandleEditFieldValueChanged(app, event)
            value =PostprocessingfunctionhandleEditFieldValueChanged.Value;
            app.Data.classiObj.outputFun=str2func(value);
            checkStatus(app,false)
        end

        % Value changed function: PostprocessingDropDown
        function PostprocessingDropDownValueChanged(app, event)
            value = app.PostprocessingDropDown.Value;
            pix=find(contains(app.PostprocessingDropDown.Items,value));

            switch pix
                case 1
                    app.Data.classiObj.outputType='proba';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                case 2
                    app.Data.classiObj.outputType='segmentation';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                case 3
                    app.Data.classiObj.outputType='postprocessing';
                    app.Data.classiObj.outputFun='post';
                    app.Data.classiObj.outputArg={'threshold','0.9'};
                case 4
                    app.Data.classiObj.outputType='postprocessing';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                    app.PostprocessingcustomfunctionhandleEditField.Value='';
            end

            checkStatus(app,false)
            displayClassi(app);
        end

        % Value changed function: 
        % PostprocessingcustomfunctionhandleEditField
        function PostprocessingcustomfunctionhandleEditFieldValueChanged2(app, event)
            value = app.PostprocessingcustomfunctionhandleEditField.Value;
            app.Data.classiObj.outputFun=value;
            checkStatus(app,false)
        end

        % Cell edit callback: UITrainingTable
        function UITrainingTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            par=app.UITrainingTable.ColumnName{indices(2)};

            if numel(str2num(newData)) % convert data if they are numeric
                app.Data.trainingParam.(par)=str2num(newData);
            else
                app.Data.trainingParam.(par)=newData;
            end

            if strcmp(par,'output')
                app.Data.classiOj.output=str2num(newData);
            end
            checkStatus(app,false)
            displayTraining(app);
        end

        % Button down function: DatasetsROIsTab
        function DatasetsROIsTabButtonDown(app, event)
            displayData(app);
        end

        % Cell edit callback: UITableData
        function UITableDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==2
                app.UITableData.Data{indices(1),1}=~app.UITableData.Data{indices(1),1};
            end
            if indices(2)==1
                app.UITableData.Data{indices(1),2}=~app.UITableData.Data{indices(1),2};
            end

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');

            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: AnnotateselectedROIButton
        function AnnotateselectedROIButtonPushed(app, event)
            sel=app.Data.selected;
            if numel(sel)==0
                uialert(app.UIFigure,'First select an ROI!','Error')
                return;
            end
            disp(['Launching ROI #' num2str(sel) '.... Please wait...']);
            app.Data.classiObj.userTraining('Roi',sel);
            checkStatus(app,false)
        end

        % Cell selection callback: UITableData
        function UITableDataCellSelection(app, event)
            indices = event.Indices;
            app.Data.selected=indices(1);
           
        end

        % Button pushed function: ImportROIsButton
        function ImportROIsButtonPushed(app, event)

            roiImporterGUI(app)
            uiwait(app.UIFigure);

            displayData(app);
            displayClassi(app); % uodates the channel in particular in the classi tab
        end

        % Callback function
        function FormattrainingsetfortrainingButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Exporting trainingset to files; PLease wait...');
            d.Value=0.33;

            output=app.Data.classiObj.formatDataForTraining;

            d.Value=0.66;

            if numel(output)>0
                d.Message=[num2str(output) ' files/images were exported; The classifier is reday to be trained!'];
            else
                d.Message=['No file was exported; Check your trainingset!'];
                pause(1)
            end

            pause(2);
            close(d)
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            for i=1:size(app.UITableData.Data,1)
                app.UITableData.Data{i,1}=true;
            end

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            for i=1:size(app.UITableData.Data,1)
                app.UITableData.Data{i,1}=false;
            end

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: removeselectedROIButton
        function removeselectedROIButtonPushed(app, event)

            sel=app.Data.selected;
            if numel(sel)
                if numel(app.Data.classiObj.roi)==1
                    app.Data.classiObj.roi=roi;
                else

                    pix=setxor(1:numel(app.Data.classiObj.roi),sel,'stable');
                    app.Data.classiObj.roi=app.Data.classiObj.roi(pix);
                end
            end

            checkStatus(app,false)
            displayData(app);

        end

        % Callback function
        function TrainclassifierButtonPushed(app, event)

            classiObj=app.Data.classiObj;

            if ~exist(fullfile(classiObj.path,'trainingParam.mat'))

                uialert(app.UIFigure,'Error','Training parameters were not saved !')

            end

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Training network; Please wait...');
            d.Value=0.5;

            classiObj.trainClassifier;

            d.Value=1;

            pause(1);
            close(d)


        end

        % Callback function
        function ValidateclassifierButtonPushed(app, event)

            classiObj=app.Data.classiObj;

            if ~exist(fullfile(classiObj.path,'trainingParam.mat'))

                uialert(app.UIFigure,'Error','Training parameters were not saved !')

            end

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Checking network classifier performance using groundtruth data; Please wait...');
            d.Value=0.5;

            classiObj.validateTrainingData;

            d.Value=1;

            pause(1);
            close(d)

        end

        % Callback function
        function DisplaystatisticsButtonPushed(app, event)
            classiObj=app.Data.classiObj;
            aa=app.UITableData.Data;

            if numel(aa)==0
                uialert(app.UIFigure,'First select the testset in the dataset tab or make sure you have ROIs!','Error');
                return;
            end


            % gather the test set.

            selectedfortest=find(cellfun(@(x) x==0,app.UITableData.Data(:,1)));
            classiObj.stats('Confusion','Classes','Rois',selectedfortest','Force');
        end

        % Menu selected function: DisplaystatisticsMenu
        function DisplaystatisticsMenuSelected(app, event)
            classiObj=app.Data.classiObj;
            aa=app.UITableData.Data;

            if numel(aa)==0
                uialert(app.UIFigure,'First select the testset in the dataset tab or make sure you have ROIs!','Error');
                return;
            end


            % gather the test set.

            selectedfortest=find(cellfun(@(x) x==0,app.UITableData.Data(:,1)));
            classiObj.stats('Confusion','Classes','Rois',selectedfortest','Force');
        end

        % Menu selected function: ValidateclassifierusingtestsetMenu
        function ValidateclassifierusingtestsetMenuSelected(app, event)

            classiObj=app.Data.classiObj;

            if ~exist(fullfile(classiObj.path,'trainingParam.mat'))
                uialert(app.UIFigure,'Error','Training parameters were not saved !')
                return;
            end
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Checking number of frames');
            d.Value=0.1;
            
            if numel(classiObj.roi(1).image)==0
                classiObj.roi(1).load;
            end
            
            nframes=size(classiObj.roi(1).image,4);
            
            prompt = {'Process only ROIs/Frames in which groundtruth is available (yes -> 1; no -> 0):','Frames to be processed:','Parallel computing (yes -> 1; no -> 0):','Use additional CNN only classification (yes -> 1; no -> 0) - for LSTM classification'};
            dlgtitle = 'Input classifier validation parameters';
            
            dims = [1 100];
            
            definput = {'1',['1:' num2str(nframes)],'0','0'};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if numel(answer)==0
                return;
            end
            
         % here bug suspected  
          if numel(app.UITableData.Data)==0
              displayData(app);
          end
           
            selectedfortesting=cellfun(@(x) x==0,app.UITableData.Data(:,1));
            
            roiobj=classiObj.roi(selectedfortesting); 
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Checking network classifier performance using groundtruth data // test dataset; Please wait...');
            d.Value=0.2;

            arg={};
            cc=1;
            if strcmp(answer{1},'1')
                arg{cc}='RoiWithGT';
                cc=cc+1;
            end
            
            if strcmp(answer{3},'1')
                arg{cc}='Parallel';
                cc=cc+1;
            end
            
            arg{cc}='Frames';
            cc=cc+1;
            arg{cc}=answer{2};
            cc=cc+1;
                
           if strcmp(answer{4},'1')
                arg{cc}='ClassifierCNN';
                cc=cc+1;
            end      
            
                arg{cc}='Progress';
                cc=cc+1;    
                arg{cc}=d;
                cc=cc+1;   
              
           classiObj.validateTrainingData(roiobj,arg{:});

            d.Value=1;

            pause(1);
            close(d)
        end

        % Menu selected function: FormattrainingsetMenu
        function FormattrainingsetMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Exporting trainingset to files; PLease wait...');
            d.Value=0.33;

            output=app.Data.classiObj.formatDataForTraining;

            d.Value=0.66;

            if numel(output)>0
                d.Message=[num2str(output) ' files/images were exported; The classifier is reday to be trained!'];
            else
                d.Message=['No file was exported; Check your trainingset!'];
                pause(1)
            end

            pause(2);
            close(d)
        end

        % Menu selected function: TrainClassifierMenu
        function TrainClassifierMenuSelected(app, event)
            classiObj=app.Data.classiObj;

            if ~exist(fullfile(classiObj.path,'trainingParam.mat'))

                uialert(app.UIFigure,'Error','Training parameters were not saved !')

            end

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Training network; Please wait...');
            d.Value=0.5;

             [~, check]=classiObj.loadClassifier('check');
             if check==1
                  evalin('base',['clear ' classiObj.strid]);   
             end
             
             checkStatus(app);
             
            classiObj.trainClassifier;

            d.Value=1;

            pause(1);
            close(d)
        end

        % Menu selected function: SaveclassifierandparametersMenu
        function SaveclassifierandparametersMenuSelected(app, event)
            disp('Saving classifiers')

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier and trainingset...');
            d.Value=0.33;

            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';

            %a=app.Data.classiObj.path

            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.trainingParam.rois=find(selectedfortraining');
            else
                app.Data.trainingParam.rois =1:numel(app.Data.classiObj.roi);
            end


            if isfolder(app.Data.classiObj.path)
                warning off all;
                mkdir([app.Data.classiObj.path '/TrainingValidation'])

                warning on all;

                trainingParam=app.Data.trainingParam;
              %  trainingParam.MiniBatchSize=trainingParam.MinBatchSize;
                
              %  trainingParam=rmfield(trainingParam,'MinBatchSize');
               
                save([app.Data.classiObj.path '/trainingParam.mat'],'trainingParam');
                save([app.Data.classiObj.path '/TrainingValidation' '/trainingParam.mat'],'trainingParam');

                d.Value=1;
                d.Message='Classifier is saved !';
            else
                d.Value=1;
                d.Message='Could not find/access the folder to save parameters; Check your connection? Quitting!';
                pause(1);
            end
            pause(0.5);
            close(d)
            
            checkStatus(app,true)
        end

        % Callback function
        function ChannelsavailableintrainingsetDropDownValueChanged(app, event)
            value = app.ChannelsavailableintrainingsetDropDown.Value;

            app.ChannelnameusedasinputEditField.Value=value;
            app.Data.classiObj.channelName=value;
            app.Data.classiObj.channel(1)= find(matches(app.ChannelsavailableintrainingsetDropDown.Items,value));
             checkStatus(app,false)
        end

        % Callback function
        function ChannelsavailableintrainingsetDropDown_2ValueChanged(app, event)
            value = app.ChannelsavailableintrainingsetDropDown_2.Value;
            app.AdditionalchannelforclassificationobjectsorregressionEditField.Value=value;
            app.Data.classiObj.channelName2=value;
            app.Data.classiObj.channel(2)= find(matches(app.ChannelsavailableintrainingsetDropDown_2.Items,value));
            checkStatus(app,false)
        end

        % Value changed function: WatershedCheckBox
        function WatershedCheckBoxValueChanged(app, event)
            value = app.WatershedCheckBox.Value;
            str= app.Data.classiObj.outputArg;

            if value==false
                str=setxor(str,'watershed','stable') ;
            else
                str=[str 'watershed'];
            end


            app.Data.classiObj.outputArg=str;
            checkStatus(app,false);
            displayClassi(app);
        end

        % Value changed function: FilteroutsmallobjectsCheckBox
        function FilteroutsmallobjectsCheckBoxValueChanged(app, event)
            value = app.FilteroutsmallobjectsCheckBox.Value;
            str= app.Data.classiObj.outputArg;

            if value==false
                pix=find(matches(str,'sizethreshold'));
                if numel(pix)
                    val=str(pix+1);
                    str=setxor(str,val{1},'stable');
                end
                str=setxor(str,'sizethreshold','stable');
                %       str
            else
                str=[str 'sizethreshold' num2str(app.SizepixelEditField.Value)];
            end

            app.Data.classiObj.outputArg=str;
            checkStatus(app,false);
            displayClassi(app);
        end

        % Value changed function: SizepixelEditField
        function SizepixelEditFieldValueChanged(app, event)
            value = app.SizepixelEditField.Value;
            str= app.Data.classiObj.outputArg;

            pix=find(matches(str,'sizethreshold'));
            if numel(pix)
                val=str(pix+1);
                str{pix+1}=num2str(value);
                app.Data.classiObj.outputArg=str;
                checkStatus(app,false);
                displayClassi(app);
            end

        end

        % Selection changed function: ThresholdingButtonGroup
        function ThresholdingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdingButtonGroup.SelectedObject;

            str= app.Data.classiObj.outputArg;

            switch selectedButton.Text
                case 'Maximum probability'

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)==0
                        str=[str 'maxproba'];
                    end

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)
                        str=setxor(str,'adaptivethreshold','stable');
                    end

                    pix=find(matches(str,'threshold'));
                    if numel(pix)
                        val=str(pix+1);
                        str=setxor(str,val{1},'stable');
                        str=setxor(str,'threshold','stable');
                    end

                case 'Adaptive threshold (Otsu method)'

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)==0
                        str=[str 'adaptivethreshold'];
                    end

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)
                        str=setxor(str,'maxproba','stable');
                    end

                    pix=find(matches(str,'threshold'));

                    if numel(pix)
                        val=str(pix+1);
                        str=setxor(str,val{1},'stable');
                        str=setxor(str,'threshold','stable');
                    end


                case 'Fixed threshold'

                    pix=find(matches(str,'threshold'));

                    if numel(pix)==0
                        str=[str 'threshold' num2str(app.ValueEditField.Value)];
                    end

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)
                        str=setxor(str,'maxproba','stable');
                    end

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)
                        str=setxor(str,'adaptivethreshold','stable');
                    end

            end

            app.Data.classiObj.outputArg=str;
            checkStatus(app,false)
            displayClassi(app);

        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            value = app.ValueEditField.Value;

            str= app.Data.classiObj.outputArg;

            pix=find(matches(str,'threshold'));
            if numel(pix)
                val=str(pix+1);
                str{pix+1}=num2str(value);
                app.Data.classiObj.outputArg=str;
                checkStatus(app,false)
                displayClassi(app);
            end

        end

        % Menu selected function: LoadclassifierMenu
        function LoadclassifierMenuSelected(app, event)
           
           classiObj=app.Data.classiObj;
           classifier=classiObj.loadClassifier;
           %assignin('base',classiObj.strid,classifier);
           checkStatus(app)
        end

        % Menu selected function: CheckstatusMenu
        function CheckstatusMenuSelected(app, event)
            checkStatus(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            classiObj=app.Data.classiObj;
            value=app.ChannelListBox.Value;
        
            if matches(app.ChannelListBoxSel.Items,value)==false
            app.ChannelListBoxSel.Items=[app.ChannelListBoxSel.Items value];
            end
            
            classiObj.channelName= app.ChannelListBoxSel.Items;
            app.Data.classiObj=classiObj;
            displayClassi(app);
            
        end

        % Button pushed function: DeSelectButton
        function DeSelectButtonPushed(app, event)
            classiObj=app.Data.classiObj;
            value=app.ChannelListBoxSel.Value;
            
           
            app.ChannelListBoxSel.Items=setxor(app.ChannelListBoxSel.Items,value);
           
            classiObj.channelName= app.ChannelListBoxSel.Items;
            app.Data.classiObj=classiObj;
            displayClassi(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveclassifierandparametersMenu = uimenu(app.FileMenu);
            app.SaveclassifierandparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveclassifierandparametersMenuSelected, true);
            app.SaveclassifierandparametersMenu.Text = 'Save classifier and parameters';
            app.ClassifierMenu = uimenu(app.UIFigure);
            app.ClassifierMenu.Text = 'Classifier';
            app.FormattrainingsetMenu = uimenu(app.ClassifierMenu);
            app.FormattrainingsetMenu.MenuSelectedFcn = createCallbackFcn(app, @FormattrainingsetMenuSelected, true);
            app.FormattrainingsetMenu.Text = 'Format training set...';
            app.TrainClassifierMenu = uimenu(app.ClassifierMenu);
            app.TrainClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainClassifierMenuSelected, true);
            app.TrainClassifierMenu.Text = 'Train Classifier...';
            app.ValidateclassifierusingtestsetMenu = uimenu(app.ClassifierMenu);
            app.ValidateclassifierusingtestsetMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateclassifierusingtestsetMenuSelected, true);
            app.ValidateclassifierusingtestsetMenu.Text = 'Validate classifier using test set...';
            app.DisplaystatisticsMenu = uimenu(app.ClassifierMenu);
            app.DisplaystatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaystatisticsMenuSelected, true);
            app.DisplaystatisticsMenu.Text = 'Display statistics...';
            app.LoadclassifierMenu = uimenu(app.ClassifierMenu);
            app.LoadclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadclassifierMenuSelected, true);
            app.LoadclassifierMenu.Text = 'Load classifier';
            app.CheckstatusMenu = uimenu(app.ClassifierMenu);
            app.CheckstatusMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckstatusMenuSelected, true);
            app.CheckstatusMenu.Separator = 'on';
            app.CheckstatusMenu.Text = 'Check status...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClassifierparametersTab = uitab(app.TabGroup);
            app.ClassifierparametersTab.Title = 'Classifier parameters';
            app.TypeLabel = uilabel(app.ClassifierparametersTab);
            app.TypeLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ClassifierparametersTab);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ClassifieroutputtypeDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.ClassifieroutputtypeDropDownLabel.Text = 'Classifier output type:';
            app.ClassifieroutputtypeDropDown = uidropdown(app.ClassifierparametersTab);
            app.ClassifieroutputtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassifieroutputtypeDropDownValueChanged, true);
            app.SpaceseparatedclassnamesLabel = uilabel(app.ClassifierparametersTab);
            app.SpaceseparatedclassnamesLabel.Text = 'Space-separated class names: ';
            app.SpaceseparatedclassnamesEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.SpaceseparatedclassnamesEditField.ValueChangedFcn = createCallbackFcn(app, @SpaceseparatedclassnamesEditFieldValueChanged, true);
            app.SpaceseparatedclassnamesEditField.Tooltip = {'For classification, class definition is mandaztory; If emtpy, the classifier will output a regression'};
            app.PostprocessingcustomfunctionhandleEditFieldLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingcustomfunctionhandleEditFieldLabel.Text = 'Post-processing custom function handle:';
            app.PostprocessingcustomfunctionhandleEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.PostprocessingcustomfunctionhandleEditField.ValueChangedFcn = createCallbackFcn(app, @PostprocessingcustomfunctionhandleEditFieldValueChanged2, true);
            app.ClassiDetailsLabel = uilabel(app.ClassifierparametersTab);
            app.ClassiDetailsLabel.Text = 'ClassiDetails';
            app.PostprocessingDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingDropDownLabel.Text = 'Post-processing';
            app.PostprocessingDropDown = uidropdown(app.ClassifierparametersTab);
            app.PostprocessingDropDown.Items = {'plain output / probabilities for each class', 'plain output / semantic segmentation', 'postprocessing', 'custom function (see below)'};
            app.PostprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @PostprocessingDropDownValueChanged, true);
            app.PostprocessingDropDown.Value = 'plain output / probabilities for each class';
            app.PostprocessingparametersPanel = uipanel(app.ClassifierparametersTab);
            app.PostprocessingparametersPanel.Title = 'Post-processing parameters';
            app.WatershedCheckBox = uicheckbox(app.PostprocessingparametersPanel);
            app.WatershedCheckBox.ValueChangedFcn = createCallbackFcn(app, @WatershedCheckBoxValueChanged, true);
            app.WatershedCheckBox.Tooltip = {'Applies a watershed segmentation on the output'};
            app.WatershedCheckBox.Text = 'Watershed';
            app.ThresholdingButtonGroup = uibuttongroup(app.PostprocessingparametersPanel);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingButtonGroupSelectionChanged, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.FixedthresholdButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.FixedthresholdButton.Text = 'Fixed threshold';
            app.FixedthresholdButton.Value = true;
            app.AdaptivethresholdOtsumethodButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.AdaptivethresholdOtsumethodButton.Text = 'Adaptive threshold (Otsu method)';
            app.MaximumprobabilityButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.MaximumprobabilityButton.Text = 'Maximum probability';
            app.ValueEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.ThresholdingButtonGroup, 'text');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.ValueEditField.Value = '0.9';
            app.FilteroutsmallobjectsCheckBox = uicheckbox(app.PostprocessingparametersPanel);
            app.FilteroutsmallobjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteroutsmallobjectsCheckBoxValueChanged, true);
            app.FilteroutsmallobjectsCheckBox.Tooltip = {'Applies a watershed segmentation on the output'};
            app.FilteroutsmallobjectsCheckBox.Text = 'Filter out small objects';
            app.SizepixelEditFieldLabel = uilabel(app.PostprocessingparametersPanel);
            app.SizepixelEditFieldLabel.Text = 'Size (pixel):';
            app.SizepixelEditField = uieditfield(app.PostprocessingparametersPanel, 'numeric');
            app.SizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @SizepixelEditFieldValueChanged, true);
            app.AvailablechannelstakenfromtrainingdatasetROIsListBoxLabel = uilabel(app.ClassifierparametersTab);
            app.AvailablechannelstakenfromtrainingdatasetROIsListBoxLabel.Text = 'Available channels (taken from training dataset (ROIs)';
            app.ChannelListBox = uilistbox(app.ClassifierparametersTab);
            app.ChannelListBox.Items = {'Item 1', 'Item 2', '', ''};
            app.ChannelListBox.Value = 'Item 2';
            app.SelectedchannelsasclassificationinputLabel = uilabel(app.ClassifierparametersTab);
            app.SelectedchannelsasclassificationinputLabel.Text = 'Selected channels as classification input';
            app.ChannelListBoxSel = uilistbox(app.ClassifierparametersTab);
            app.ChannelListBoxSel.Items = {'Item 1', '', '', ''};
            app.SelectButton = uibutton(app.ClassifierparametersTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = '>';
            app.DeSelectButton = uibutton(app.ClassifierparametersTab, 'push');
            app.DeSelectButton.ButtonPushedFcn = createCallbackFcn(app, @DeSelectButtonPushed, true);
            app.DeSelectButton.Text = '<';
            app.TrainingprocedureparametersTab = uitab(app.TabGroup);
            app.TrainingprocedureparametersTab.Title = 'Training procedure parameters';
            app.TrainingprocedureparametersTab.ButtonDownFcn = createCallbackFcn(app, @TrainingprocedureparametersTabButtonDown, true);
            app.UITrainingTable = uitable(app.TrainingprocedureparametersTab);
            app.UITrainingTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITrainingTable.RowName = {};
            app.UITrainingTable.CellEditCallback = createCallbackFcn(app, @UITrainingTableCellEdit, true);
            app.DatasetsROIsTab = uitab(app.TabGroup);
            app.DatasetsROIsTab.Title = 'Datasets (ROIs)';
            app.DatasetsROIsTab.ButtonDownFcn = createCallbackFcn(app, @DatasetsROIsTabButtonDown, true);
            app.DatasetsROIsTab.Tag = 'list';
            app.UITableData = uitable(app.DatasetsROIsTab);
            app.UITableData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData.RowName = {};
            app.UITableData.CellEditCallback = createCallbackFcn(app, @UITableDataCellEdit, true);
            app.UITableData.CellSelectionCallback = createCallbackFcn(app, @UITableDataCellSelection, true);
            app.ImportROIsButton = uibutton(app.DatasetsROIsTab, 'push');
            app.ImportROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportROIsButtonPushed, true);
            app.ImportROIsButton.Text = 'Import ROIs...';
            app.AnnotateselectedROIButton = uibutton(app.DatasetsROIsTab, 'push');
            app.AnnotateselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateselectedROIButtonPushed, true);
            app.AnnotateselectedROIButton.Tooltip = {'Select a ROI in the ROI Index column first'};
            app.AnnotateselectedROIButton.Text = 'Annotate selected ROI...';
            app.SelectallButton = uibutton(app.DatasetsROIsTab, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.DatasetsROIsTab, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.removeselectedROIButton = uibutton(app.DatasetsROIsTab, 'push');
            app.removeselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedROIButtonPushed, true);
            app.removeselectedROIButton.Text = 'remove selected ROI';
            app.StatusSaved = uilamp(app.UIFigure);
            app.StatusSaved.Tooltip = {'Classifier status'};
            app.StatusLoad = uilamp(app.UIFigure);
            app.StatusLoad.Tooltip = {'Classifier loaded in memory'};
            app.ParametersaresavedLabel = uilabel(app.UIFigure);
            app.ParametersaresavedLabel.Text = 'Parameters are saved:';
            app.ClassifierisloadedinworksapcememoryLabel = uilabel(app.UIFigure);
            app.ClassifierisloadedinworksapcememoryLabel.Text = 'Classifier is loaded in worksapce (memory):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end