classdef classifyDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifydataUIFigure          matlab.ui.Figure
        ApplyselectedsettingstoallButton  matlab.ui.control.Button
        DeselectallButton             matlab.ui.control.Button
        SelectallButton               matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        ClassifyselecteddataButton    matlab.ui.control.Button
        SelectpositionsandenterROIsarrayLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SelectclassfiermodelDropDown  matlab.ui.control.DropDown
        SelectclassfiermodelDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        
        function updateTable(app)
            
            str= app.SelectclassfiermodelDropDown.Value;
            shallowObj=app.Data.shallowObj;
            t=app.UITable;
            t.ColumnName={'Select','Position','Position Name','Select channel to classify','Select ROIs array','Select frames'};
            t.ColumnEditable=[true false false true true true];
            
            str2={};
            for i=1:numel(shallowObj.fov)
             if numel(shallowObj.fov(i).roi(1).id)
                 tmp= shallowObj.fov(i).roi(1).display.channel;
                 str2=unique([tmp,str2]);
             end
            end
            
            t.ColumnFormat={[] [] [] str2 [] []};
            
            Data={};
            
            for i=1:numel(shallowObj.fov)
                
                strcha='';
                
                if numel(shallowObj.fov(i).roi(1).id)
                    cha=shallowObj.fov(i).roi(1).display.channel;
                  % txt= shallowObj.processing.classification(1).channelName;
                   if numel(find(matches(cha,shallowObj.processing.classification(1).channelName)))
                       strcha=shallowObj.processing.classification(1).channelName;
                   else
                       aa=shallowObj.fov(i).roi(1).display.channel;
                       if ischar(aa)
                          strcha=aa;
                       else
                          strcha=shallowObj.fov(i).roi(1).display.channel{1};  
                       end
                      
                   end
                end

                if numel(shallowObj.fov(i).frames)>1
                     pix=find(matches(shallowObj.fov(i).channel,strcha));
                     if numel(pix)
                    fra=shallowObj.fov(i).frames(pix);
                     else
                    fra=shallowObj.fov(i).frames(1);     
                     end
                else
                    fra=shallowObj.fov(i).frames;
                end
                
                Data(i,:)={true num2str(i) shallowObj.fov(i).id strcha ['1:' num2str(numel(shallowObj.fov(i).roi))] ['1:' num2str(fra)]};
            end
            
            t.Data=Data;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj)
            app.Data.shallowObj=shallowObj;
            
            str={};
            for i=1:numel(shallowObj.processing.classification)
                str{i}=shallowObj.processing.classification(i).strid;
            end
            app.SelectclassfiermodelDropDown.Items=str;
            
            updateTable(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ClassifydataUIFigure
        function ClassifydataUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SelectclassfiermodelDropDown
        function SelectclassfiermodelDropDownValueChanged(app, event)
            value = app.SelectclassfiermodelDropDown.Value;
            
            shallowObj=app.Data.shallowObj;
            
          %  pix=find(contains(app.SelectclassfiermodelDropDown.Items,value));
            
            updateTable(app)
            
%             dat=app.UITable.Data;
%             
%             dat{:,4}=num2str(shallowObj.processing.classification(pix).channel);
%             
%             app.UITable.Data=dat;
        end

        % Button pushed function: ClassifyselecteddataButton
        function ClassifyselecteddataButtonPushed(app, event)
             shallowObj=app.Data.shallowObj;
            
             list=app.SelectclassfiermodelDropDown.Items;
             valueClassi=find(contains(list,app.SelectclassfiermodelDropDown.Value));
             
             data=app.UITable.Data;
             
             selpos=find(cellfun(@(x) x==1,data(:,1)));
             
             roiscell={};
             frames={};
             channel=[];
             
             for i=1:numel(selpos)
             roiscell{i}=str2num(data{selpos(i),5});
             
             pix=find(matches(shallowObj.fov(selpos(i)).roi(1).display.channel,data{selpos(i),4}));
             channel(i)=pix;
             frames{i}=str2num(data{selpos(i),6});
             end

           %  channel
          

            % roiscell
             d = uiprogressdlg(app.ClassifydataUIFigure,'Title','Please Wait...',...
                    'Message','Starting data classification...');
            d.Value=0.01;
            
   
             shallowObj.classifyData(valueClassi,'Fovs',selpos,'Rois',roiscell,'Progress',d,'Channel',channel,'Frames',frames);
             
           %  uialert(app.ClassifydataUIFigure,'Don t forget to save the project!!','Warning');
             
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            data=app.UITable.Data;
             
            data(:,1)={true};
             
             app.UITable.Data=data;
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
             data=app.UITable.Data;
             
            data(:,1)={false};
             
             app.UITable.Data=data;
        end

        % Button pushed function: ApplyselectedsettingstoallButton
        function ApplyselectedsettingstoallButtonPushed(app, event)
             data=app.UITable.Data;
             
             selectedfovs=find(cellfun(@(x) x==1,data(:,1)));
             
             if numel(selectedfovs)==0
                 uialert(app.ClassifydataUIFigure,'No position was selected','Error');
             end
             
             for i=1:size(data,1)
                 
                 if i~=selectedfovs(1)
                     
                     data(i,4:end)=data(selectedfovs(1),4:end);
                     
                 end
             end
             
             app.UITable.Data=data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifydataUIFigure = uifigure('Visible', 'off');
            app.ClassifydataUIFigure.Name = 'Classify data';
            app.ClassifydataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifydataUIFigureCloseRequest, true);
            app.SelectclassfiermodelDropDownLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectclassfiermodelDropDownLabel.Text = 'Select classfier/model: ';
            app.SelectclassfiermodelDropDown = uidropdown(app.ClassifydataUIFigure);
            app.SelectclassfiermodelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectclassfiermodelDropDownValueChanged, true);
            app.UITable = uitable(app.ClassifydataUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectpositionsandenterROIsarrayLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectpositionsandenterROIsarrayLabel.Text = 'Select positions and enter ROIs array:';
            app.ClassifyselecteddataButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ClassifyselecteddataButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyselecteddataButtonPushed, true);
            app.ClassifyselecteddataButton.Text = 'Classify selected data';
            app.CloseButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.ApplyselectedsettingstoallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ApplyselectedsettingstoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyselectedsettingstoallButtonPushed, true);
            app.ApplyselectedsettingstoallButton.Text = 'Apply selected settings to all';
            % Show the figure after all components are created
            app.ClassifydataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifyDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifydataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifydataUIFigure)
        end
    end
end