classdef detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DivisioncountingandRLSanalysisUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoaddatagroupsMenu             matlab.ui.container.Menu
        SavedatagroupsasMenu           matlab.ui.container.Menu
        PlotLineagesButton             matlab.ui.control.Button
        PlotRLSButton                  matlab.ui.control.Button
        PlotdivisiontimesButton        matlab.ui.control.Button
        DatatypeDropDown               matlab.ui.control.DropDown
        DatatypeDropDownLabel          matlab.ui.control.Label
        PlotoptionsPanel               matlab.ui.container.Panel
        SetfileButton                  matlab.ui.control.Button
        SetdirButton                   matlab.ui.control.Button
        ExportdatafileEditField        matlab.ui.control.EditField
        ExportdatafileEditFieldLabel   matlab.ui.control.Label
        ExportplotdirEditField         matlab.ui.control.EditField
        ExportplotdirEditFieldLabel    matlab.ui.control.Label
        ExportdataasxlsxButton         matlab.ui.control.Button
        PlotselecteddatasourcesButton  matlab.ui.control.Button
        DatasourcePanel                matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        SaveselectionButton            matlab.ui.control.Button
        ROIselectionPanel              matlab.ui.container.Panel
        DatagroupsPanel                matlab.ui.container.Panel
        AssignselectedgroupparamtoallButton  matlab.ui.control.Button
        RenamegroupEditField           matlab.ui.control.EditField
        RenamegroupEditFieldLabel      matlab.ui.control.Label
        DeleteselectedgroupButton      matlab.ui.control.Button
        NewgroupButton                 matlab.ui.control.Button
        DatagroupsListBox              matlab.ui.control.ListBox
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)
        function  gatherVarsFromWorkspace(app)

            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            stclassi=struct('Classifier',{[]},'Projectclassi',{[]});

            cc=0;
            cd=0;
            app.Data.st=[];
            app.Data.stclassi=[];

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});

                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};
                    tmpclassi2=classi;

                    for k=1:numel(tmp.processing.classification)
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];

                        tmpclassi2(k)= tmp.processing.classification(k);
                    end


                    st.Projectclassi{cc}=tmpclassi;
                    stclassi.Projectclassi{cc}=tmpclassi2;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')

                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};

                    stclassi.Classifier{cd}=evalin('base',varlist{i});
                    % aa= st.Classifier{cd}
                end

            end

            %  st
            app.Data.st=st;
            app.Data.stclassi=stclassi;
            %    app.Data.convert={};
        end


        function param = newGroup(app)
            tip={'Select how single trajectories should be averaged',...
                'Check to display single cell traj',...
                'Check to display single cell plot',...
                'Check to display population averages'};

            param=struct('Traj_synchronization',{{'birth','sep','death','birth'}},...
                                  'Display_single_cell_traj',true,...
                                  'Display_single_cell_plot',false,...
                                  'Display_average',true,...
                                  'tip',{tip});

      
        end

        function fields = gatherROIsfields(app,rois,datatype)
            fields=[];
            %fields={[] [] []};
            fields.Results={[] [] []};

            if numel(rois)==0
                return
            end

            % results fields
            tmpr={};
            idx={};

            cc=1;

            for i=1:numel(rois)
                   rois(i).load('data');
                for j=1:numel(rois(i).data)
                    if rois(i).data(j).type==string(datatype)
                    tmpr=[tmpr rois(i).data(j).groupid];
                    idx=[idx rois(i).data(j).id];
                    cc=cc+1;
                    end
                end
            end

            [tmpr,ia]=unique(tmpr);

            %   tmpr=tmpr(ia)


            subt={};
            subcat={};


            for i=1:numel(tmpr)

                tt={};
                catr={};

                for j=1:numel(rois)

                    pix=find(matches({rois(j).data.groupid},tmpr(i)));

                    if numel(pix)
                        %pix=matches(tmpr2,tmpr{i});

                        % if sum(pix)>0

                        subtmpr=rois(j).data(pix).data.Properties.VariableNames;

                        si=rois(j).data(pix).dataSize;

                        for k=1:numel(subtmpr)
                            %
                            %                                 if ~isstruct(rois(j).results.(tmpr2{pix}).(subtmpr{k}))
                            %
                            tt=[tt subtmpr(k)];
                            %

                            catr=[catr [' // ' num2str(si(1)) ' elements // ' class(rois(j).data(pix).data.(subtmpr{k})) ' ']];
                            %                                 end
                            %                             end
                        end


                    end
                    %   end
                end

                [ttt,iaa,~]=unique(tt);
                catrr=catr(iaa);

                subt{i}=ttt;
                subcat{i}=catrr;
            end

            % subt,subcat
            fields.Results={tmpr subt}; % subcat};
        end
    end

    methods (Access = public)
        function display2(app,datapicker)
            d = uiprogressdlg(app.DivisioncountingandRLSanalysisUIFigure,'Title','Please Wait...',...
                'Message','Loading data group...');
            d.Value=0.33;

            groups=evalin('base','datagroups');

            %groups = app.Data.groups;
            items= {groups.Name};

            if numel(app.DatagroupsListBox.Value)
                aa=app.DatagroupsListBox.Value;
                pix=find(matches(items,aa));

                if numel(pix)==0
                    pix=1;
                else
                    pix=pix(1);
                end
            else
                pix=1;
            end

            % create panel with plotting options
            app.DatagroupsListBox.Items=items;
            app.DatagroupsListBox.Value=items{pix};

            param=groups(pix).Param;

            tip=param.tip;
            param=rmfield(param,'tip');

            if numel(app.PlotoptionsPanel.Children)
                delete(app.PlotoptionsPanel.Children)
                app.PlotoptionsPanel.UserData=[];
            end

            struct2GUI(param,[10 150],'Handle',app.PlotoptionsPanel,'Tip',tip,'Flag',app.Lamp);
            param.tip=tip;
            app.RenamegroupEditField.Value=aa;

            if nargin==2 % creates ROI picker
                if numel(groups(pix).Data.nodeid)
                    %nodes=app.Data.groups(pix).Data;Data
                    nodes=groups(pix).Data.nodeid;
                else
                    nodes=[];
                end
                %  no=app.Data.groups(pix).Data.filepath{1}

                if isa('datatree','var')
                    if ishandle(datatree)
                        delete(datatree)
                    end
                end

                d.Value=0.5;
                d.Message='Refreshing data picker...';

                [datatree,~]=datapickerGUI2('Handle',app.ROIselectionPanel,'Flag',app.Lamp,'Position',[12 12 240 350],'Input',nodes);
                app.Data.tree=datatree;
                %  tmp=app.Data.tree.UserData
                drawnow
            end

            % create data source picker 
            rois=groups(pix).Data.roiobj;

            if isfield(app.Data, 'sourcetree')
                delete(app.Data.sourcetree)
            end
            
            Tree = uitree(app.DatasourcePanel,'checkbox');
            Tree.CheckedNodesChangedFcn = {@checkChanged, app.Lamp};
            Tree.Tooltip = {'Select data to be plotted or exported'};
            Tree.Position = [10 10 300 310];
            app.Data.sourcetree=Tree;

            if numel(roi)
            typedata=groups(pix).Type; %Param.Plot_type{end};

            fields=gatherROIsfields(app,rois,typedata);

            if numel(groups(pix).Source)==0
            sourceid={};
            else
            sourceid=groups(pix).Source.nodeid;
            end

            checkedNodes=[];

            for i=1:numel(fields.Results{1})
                if numel(fields.Results{2}{i})
                    h3(i)=uitreenode(Tree,'Text',fields.Results{1}{i},'Tag','Results','UserData',[]);

                    for j=1:numel(fields.Results{2}{i})

                        str= fields.Results{2}{i}{j}; % fields.Results{3}{i}{j}];
                        h4(i,j)=uitreenode(h3(i),'Text',str,'Tag',['Results' num2str(i) '_' num2str(j)],'UserData',...
                            {fields.Results{1}{i} fields.Results{2}{i}{j}} );
                        if numel(sourceid)
                            if numel(find(matches(sourceid,['Results' num2str(i) '_' num2str(j)])))
                                checkedNodes=[checkedNodes; h4(i,j)];
                          end
                        end
                    end
                end
            end

            Tree.CheckedNodes=checkedNodes;

            expand(Tree);
            end

            % enable buttons based on checked data. 
            sourceid % here

            function checkChanged(src, event, flag)

                if numel(flag)
                    flag.Color=[1 0 0];
                end

                nodeid={};
                nodename={};

                checkedN = event.LeafCheckedNodes;

                if numel(checkedN)
                    for k=1:numel(checkedN)
                          nodeid{end+1}=checkedN(k).Tag;
                          nodename{end+1}=checkedN(k).UserData;
                    end
                end

                src.UserData.nodeid=nodeid;
                src.UserData.nodename=nodename;
            end

            d.Value=0.9;
            d.Message='Data loaded...';
            close(d)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data=[];

            %gatherVarsFromWorkspace(app)

            varlist=evalin('base','who');

            ok=0;
            for i=1:numel(varlist)
                if strcmp(varlist{i},'datagroups')
                   % groups=evalin('base','datagroups');
                    ok=1;
                    break
                end
            end

            if ok==0
                param = newGroup(app);

                groups=struct('Name','group01','Data',[],'Param',param,'Table',[],'Source',[],'Type','temporal');
                groups.Data.nodeid=[];
                groups.Data.roiobj=[];
                groups.Source.nodeid={};
                groups.tip=param.tip;

                assignin('base','datagroups',groups);
            end

            display2(app,'datapicker')

        end

        % Value changed function: DatagroupsListBox
        function DatagroupsListBoxValueChanged(app, event)
            tmp=app.DatagroupsListBox.Value;
            app.Data.selectedgroup = tmp;
            display2(app,'refresh')
        end

        % Value changed function: RenamegroupEditField
        function RenamegroupEditFieldValueChanged(app, event)
            value = app.RenamegroupEditField.Value;

            groups=evalin('base','datagroups');

            if numel(app.DatagroupsListBox.Value)
                pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                pix=pix(1);
                groups(pix).Name=value;
                app.DatagroupsListBox.Items{pix}=value;
                app.DatagroupsListBox.Value=value;
            end

            assignin('base','datagroups',groups);
        end

        % Button pushed function: NewgroupButton
        function NewgroupButtonPushed(app, event)
            pix=numel(app.DatagroupsListBox.Items);

            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));

            groups=evalin('base','datagroups');

            % take param value from selected position
            
            if numel(sel)
                param= groups(sel).Param;
            else
                param = newGroup(app);

            end

            app.DatagroupsListBox.Items=[app.DatagroupsListBox.Items {['group' num2str(pix+1)]}];
            app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{end};

            %  aa= size(app.Data.groups)

           tmp(2)=groups(pix);
 % HERE BUG with new group , inhierting ROI handles 

            groups(pix+1)=tmp(1); % empty array

            groups(pix+1).Name=app.DatagroupsListBox.Value;
            groups(pix+1).Param=param;
            groups(pix+1).Source=[];
            groups(pix+1).tip=param.tip;
            groups(pix+1).Data.type=groups(pix).Data.type;
            groups(pix+1).Data.filepath=groups(pix).Data.filepath;
            groups(pix+1).Data.nodeid={};
            groups(pix+1).Data.roiobj=[];

            assignin('base','datagroups',groups);

            display2(app)

        end

        % Button pushed function: DeleteselectedgroupButton
        function DeleteselectedgroupButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            groups=evalin('base','datagroups');

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                if numel(li)
                app.DatagroupsListBox.Items=app.DatagroupsListBox.Items(li);
                %groups= app.Data.groups(li);
                app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{1};
                groups= groups(li);
                end
            end

            assignin('base','datagroups',groups);

            display2(app)
        end

        % Button pushed function: SaveselectionButton
        function SaveselectionButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            groups=evalin('base','datagroups');

            if numel(sel)
                %app.Data.groups(sel).Param=app.DivisionextractionparametersPanel.UserData;

                if numel(app.Data.tree.UserData)
                    if numel(app.Data.tree.UserData.nodeid)
                    groups(sel).Data.nodeid= app.Data.tree.UserData.nodeid;
                    groups(sel).Data.roiobj= app.Data.tree.UserData.roiobj;
                    groups(sel).Data.filepath= app.Data.tree.UserData.filepath;
                    groups(sel).Data.type= app.Data.tree.UserData.type;
                    end
                end

                if numel(app.PlotoptionsPanel.UserData)
                    groups(sel).Param=app.PlotoptionsPanel.UserData;
                    groups(sel).Param.tip=groups(sel).tip;

                    % here : if you change birth vs temporal, then clear
                    % the source tree below !!!
                 %    groups(sel).Source.nodename=[];
                   %  groups(sel).Source.nodeid=[];
                end

                if numel(app.Data.sourcetree.UserData)
                    groups(sel).Source=app.Data.sourcetree.UserData;     
                end

                app.Lamp.Color=[0 1 0];
                assignin('base','datagroups',groups);
            end

            display2(app)

        end

        % Button pushed function: AssignselectedgroupparamtoallButton
        function AssignselectedgroupparamtoallButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            groups=evalin('base','datagroups');

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                if numel(li)
                    for i=li
                        groups(i).Param=app.Data.groups(sel).Param;
                        groups(i).Data=app.Data.groups(sel).Data;
                    end
                end
            else
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select a data group !','Warning');
            end

            assignin('base','datagroups',groups);

        end

        % Menu selected function: LoaddatagroupsMenu
        function LoaddatagroupsMenuSelected(app, event)

            [file,path] = uigetfile('*.mat','Select a shallow project',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                load(filename);

                % check if projects are loaded ; if not , load them ...
                for i=1:numel(groups)
                    %  tmp=groups(i).Data
                    for j=1:numel(groups(i).Data.filepath)
                        if numel(groups(i).Data.filepath{j})
                            if strcmp(groups(i).Data.type{j},'shallow')
                                sha=shallowLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=sha.io.file;
                                    assignin('base',name,sha);
                                end

                            end
                            if strcmp(groups(i).Data.type{j},'classi')
                                sha=classiLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=[sha.strid '_indep'];
                                    assignin('base',name,sha);
                                end
                            end


                        end
                    end
                end

                assignin('base','datagroups',groups);

                display2(app,'refresh');
            end

        end

        % Menu selected function: SavedatagroupsasMenu
        function SavedatagroupsasMenuSelected(app, event)
            [file,path] = uiputfile('*.mat','Choose a data group filename',pwd);
              
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                groups=evalin('base','datagroups');

                save(filename, 'groups');
            end
        end

        % Button pushed function: ExportdataasxlsxButton
        function ExportdataasxlsxButtonPushed(app, event)


             groups=evalin('base','datagroups');

            %groups = app.Data.groups;
            items= {groups.Name};

            if numel(app.DatagroupsListBox.Value)
                aa=app.DatagroupsListBox.Value;
                pix=find(matches(items,aa));

                if numel(pix)==0
                    pix=1;
                else
                    pix=pix(1);
                end
            else
                pix=1;
            end

            rois=groups(pix).Data.roiobj;
             
            if numel(rois)==0
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select at least one ROI !', 'Warning');
                return;
            end

if numel(app.ExportdatafileEditField.Value)
            filename=app.ExportdatafileEditField.Value;
else
uialert(app.DivisioncountingandRLSanalysisUIFigure,'Please specify a filename first !','Warning');
return;
end

            if numel(filename)==0
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select a valid filename first !', 'Warning');
                return;
            end

            datagroup=groups(pix);
            exportData(datagroup,rois,filename)

        end

        % Button pushed function: SetfileButton
        function SetfileButtonPushed(app, event)
             [file,path] = uiputfile('*.xlsx','Enter the path and filename of the exported file',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);
                app.ExportdatafileEditField.Value=filename;
                app.Data.file=filename;
            end
        end

        % Button pushed function: SetdirButton
        function SetdirButtonPushed(app, event)
              path = uigetdir('Enter the path of the exported plots',pwd);
            if path==0
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path)]);
                %filename=fullfile(path, file);
                app.ExportplotdirEditField.Value=path;
                app.Data.dir=path;
            end
        end

        % Button pushed function: PlotselecteddatasourcesButton
        function PlotselecteddatasourcesButtonPushed(app, event)
            
             groups=evalin('base','datagroups');

%             %groups = app.Data.groups;
%             items= {groups.Name};
% 
%             if numel(app.DatagroupsListBox.Value)
%                 aa=app.DatagroupsListBox.Value;
%                 pix=find(matches(items,aa));
% 
%                 if numel(pix)==0
%                     pix=1;
%                 else
%                     pix=pix(1);
%                 end
%             else
%                 pix=1;
%             end

for i=1:numel(groups)
            rois=groups(i).Data.roiobj;
             
            if numel(rois)==0
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select at least one ROI in each group !', 'Warning');
                return;
            end
end

 filename=app.ExportplotdirEditField.Value;

if numel(filename)==0
uialert(app.DivisioncountingandRLSanalysisUIFigure,'Please specify a filename first !','Warning');
return;
end

            if numel(filename)==0
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select a valid filename first !', 'Warning');
                return;
            end

          %  datagroup=groups(pix);

            plotData(groups,filename)

        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            value = app.DatatypeDropDown.Value;

            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            groups=evalin('base','datagroups');

            if numel(sel)
                
                groups(sel).Type=value;
                groups(sel).Source.nodename=[];
                groups(sel).Source.nodeid=[];

                app.Lamp.Color=[1 0 0];
                assignin('base','datagroups',groups);
                display2(app)
            end


        end

        % Button pushed function: PlotdivisiontimesButton
        function PlotdivisiontimesButtonPushed(app, event)
            
        end

        % Button pushed function: PlotRLSButton
        function PlotRLSButtonPushed(app, event)
            
        end

        % Button pushed function: PlotLineagesButton
        function PlotLineagesButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DivisioncountingandRLSanalysisUIFigure = uifigure('Visible', 'off');
            app.DivisioncountingandRLSanalysisUIFigure.Name = 'Division counting and & RLS analysis';
            app.FileMenu = uimenu(app.DivisioncountingandRLSanalysisUIFigure);
            app.FileMenu.Text = 'File...';
            app.LoaddatagroupsMenu = uimenu(app.FileMenu);
            app.LoaddatagroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddatagroupsMenuSelected, true);
            app.LoaddatagroupsMenu.Text = 'Load data groups...';
            app.SavedatagroupsasMenu = uimenu(app.FileMenu);
            app.SavedatagroupsasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedatagroupsasMenuSelected, true);
            app.SavedatagroupsasMenu.Text = 'Save data groups as...';
            app.DatagroupsPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatagroupsPanel.Title = 'Data groups';
            app.DatagroupsListBox = uilistbox(app.DatagroupsPanel);
            app.DatagroupsListBox.Items = {'group01', 'group02'};
            app.DatagroupsListBox.ValueChangedFcn = createCallbackFcn(app, @DatagroupsListBoxValueChanged, true);
            app.DatagroupsListBox.Value = 'group01';
            app.NewgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.NewgroupButton.ButtonPushedFcn = createCallbackFcn(app, @NewgroupButtonPushed, true);
            app.NewgroupButton.Text = 'New group';
            app.DeleteselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.DeleteselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedgroupButtonPushed, true);
            app.DeleteselectedgroupButton.Text = 'Delete selected group';
            app.RenamegroupEditFieldLabel = uilabel(app.DatagroupsPanel);
            app.RenamegroupEditFieldLabel.Text = 'Rename group:';
            app.RenamegroupEditField = uieditfield(app.DatagroupsPanel, 'text');
            app.RenamegroupEditField.ValueChangedFcn = createCallbackFcn(app, @RenamegroupEditFieldValueChanged, true);
            app.AssignselectedgroupparamtoallButton = uibutton(app.DatagroupsPanel, 'push');
            app.AssignselectedgroupparamtoallButton.ButtonPushedFcn = createCallbackFcn(app, @AssignselectedgroupparamtoallButtonPushed, true);
            app.AssignselectedgroupparamtoallButton.Text = 'Assign selected group param to all';
            app.ROIselectionPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.ROIselectionPanel.Title = 'ROI selection';
            app.SaveselectionButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.SaveselectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectionButtonPushed, true);
            app.SaveselectionButton.Text = 'Save selection';
            app.Lamp = uilamp(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatasourcePanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatasourcePanel.Title = 'Data source';
            app.PlotselecteddatasourcesButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.PlotselecteddatasourcesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotselecteddatasourcesButtonPushed, true);
            app.PlotselecteddatasourcesButton.Enable = 'off';
            app.PlotselecteddatasourcesButton.Tooltip = {'Plot matlab figures for all selected groups and data sources, export figures as .fig and .pdf, and export avarage data as .xlsx'};
            app.PlotselecteddatasourcesButton.Text = 'Plot selected data sources';
            app.ExportdataasxlsxButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.ExportdataasxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataasxlsxButtonPushed, true);
            app.ExportdataasxlsxButton.Text = 'Export data as .xlsx';
            app.ExportplotdirEditFieldLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.ExportplotdirEditFieldLabel.Text = 'Export plot dir';
            app.ExportplotdirEditField = uieditfield(app.DivisioncountingandRLSanalysisUIFigure, 'text');
            app.ExportdatafileEditFieldLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.ExportdatafileEditFieldLabel.Text = 'Export  data file';
            app.ExportdatafileEditField = uieditfield(app.DivisioncountingandRLSanalysisUIFigure, 'text');
            app.SetdirButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.SetdirButton.ButtonPushedFcn = createCallbackFcn(app, @SetdirButtonPushed, true);
            app.SetdirButton.Text = 'Set dir...';
            app.SetfileButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.SetfileButton.ButtonPushedFcn = createCallbackFcn(app, @SetfileButtonPushed, true);
            app.SetfileButton.Text = 'Set file...';
            app.PlotoptionsPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.DatatypeDropDownLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatatypeDropDown.Items = {'temporal', 'generation'};
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.DatatypeDropDown.Value = 'temporal';
            app.PlotdivisiontimesButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.PlotdivisiontimesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdivisiontimesButtonPushed, true);
            app.PlotdivisiontimesButton.Enable = 'off';
            app.PlotdivisiontimesButton.Tooltip = {'this is possible if a "divduration" dataset is available in the "generation" data mode'};
            app.PlotdivisiontimesButton.Text = 'Plot division times';
            app.PlotRLSButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.PlotRLSButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRLSButtonPushed, true);
            app.PlotRLSButton.Enable = 'off';
            app.PlotRLSButton.Tooltip = {'this is possible if an "event" dataset is available in the "generation" data mode'};
            app.PlotRLSButton.Text = 'Plot RLS';
            app.PlotLineagesButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.PlotLineagesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLineagesButtonPushed, true);
            app.PlotLineagesButton.Enable = 'off';
            app.PlotLineagesButton.Tooltip = {'this is possible if a "lineage" dataset is available in the "generation" data mode'};
            app.PlotLineagesButton.Text = 'Plot Lineages';
            % Show the figure after all components are created
            app.DivisioncountingandRLSanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DivisioncountingandRLSanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DivisioncountingandRLSanalysisUIFigure)
        end
    end
end