classdef lifespan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifespanizerUIFigure       matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewprojectMenu             matlab.ui.container.Menu
        OpenprojectMenu            matlab.ui.container.Menu
        SaveprojectMenu            matlab.ui.container.Menu
        CloseprojectMenu           matlab.ui.container.Menu
        Label                      matlab.ui.control.Label
        ExportPlotdataButton       matlab.ui.control.Button
        SelectRunclassifierButton  matlab.ui.control.Button
        RunpreprocessingButton     matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        LoadAdddataButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        Data % Description
        Settings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAdddataButton
        function LoadAdddataButtonPushed(app, event)
          % Check if the 'projname' field exists in app.Settings
   
    if isfield(app.Settings, 'projname') && ~isempty(app.Settings.projname)
        projName = app.Settings.projname;
        % Check if the variable exists in the base workspace
        if evalin('base', sprintf('exist(''%s'', ''var'')', projName))
            shallowObj = evalin('base', projName);
            loadData(shallowObj,'noROIextraction');
        else
            uialert(app.LifespanizerUIFigure, ...
                'The specified project does not exist in the workspace.', ...
                'Error');
        end
    else
        uialert(app.LifespanizerUIFigure, ...
            'No open project is defined in app.Settings.', ...
            'Error');
    end
        end

        % Button pushed function: RunpreprocessingButton
        function RunpreprocessingButtonPushed(app, event)
            
        end

        % Button pushed function: SelectRunclassifierButton
        function SelectRunclassifierButtonPushed(app, event)
            
        end

        % Button pushed function: ExportPlotdataButton
        function ExportPlotdataButtonPushed(app, event)
            
        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
                        proj=shallowNew;
            if numel(proj)==0
                return;
            end
            name=proj.io.file;
            assignin('base',name,proj);
            app.Settings.projname=name;
             app.Label.Text= fullfile( proj.io.path, [proj.io.file, '.mat']);

        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
     % Display a progress dialog
    d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
        'Message', 'Loading selected project...');
    d.Value = 0.33;

    [proj, msg] = shallowLoad;
    
    % Si le projet est vide, on examine le message
    if isempty(proj)
        if contains(msg, 'Project is already in the workspace under the var name:')
            % Extraction du nom de variable à partir du message
            tokens = regexp(msg, 'Project is already in the workspace under the var name:\s*(.*?)\s*; Quitting\.\.\.', 'tokens');
            if ~isempty(tokens)
                projName = tokens{1}{1};  % Extraction du premier élément
                % Vérifier que la variable existe dans l'espace de travail de base
                if evalin('base', sprintf('exist(''%s'', ''var'')', projName))
                    proj = evalin('base', projName);
                    app.Settings.projname = projName;
                else
                    uialert(app.LifespanizerUIFigure, ...
                        'The project variable extracted from the message does not exist in the base workspace.', ...
                        'Error');
                    close(d);
                    return;
                end
            else
                uialert(app.LifespanizerUIFigure, ...
                    'Unable to extract the variable name from the message.', 'Error');
                close(d);
                return;
            end
        elseif contains(msg, 'Wrong file name loaded')
            uialert(app.LifespanizerUIFigure, 'Wrong file name loaded.', 'Error');
            close(d);
            return;
        else
            uialert(app.LifespanizerUIFigure, sprintf('Unexpected error: %s', msg), 'Error');
            close(d);
            return;
        end
    end
    
    % Vérifier que le projet chargé est bien un objet de la classe shallow
    if ~isa(proj, 'shallow')
        uialert(app.LifespanizerUIFigure, ...
            'Loaded project is not a valid shallow object.', 'Error');
        close(d);
        return;
    end

    d.Value = 0.66;
    pause(0.2);
    
    % Affichage du projet dans la console (pour débogage)
    %disp(proj);
    
    % Récupérer le nom du projet depuis l'objet et l'assigner dans l'espace de travail et dans app.Settings
    name = proj.io.file;
    assignin('base', name, proj);
    app.Settings.projname = name;

    app.Label.Text= fullfile( proj.io.path, [proj.io.file, '.mat']);
    
    close(d);

        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            if isfield(app.Settings,'projname')    
                proj=app.Settings.projname;

                shallowObj=evalin('base',proj);

                if numel(shallowObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                end

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                shallowSave(shallowObj,[],d);
                close(d);
            else
        uialert(app.LifespanizerUIFigure,'There is no open project!','Warning','Icon','warning');
            end
        end

        % Menu selected function: CloseprojectMenu
        function CloseprojectMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifespanizerUIFigure = uifigure('Visible', 'off');
            app.LifespanizerUIFigure.Name = 'Lifespanizer';
            app.FileMenu = uimenu(app.LifespanizerUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprojectMenu = uimenu(app.FileMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project';
            app.CloseprojectMenu = uimenu(app.FileMenu);
            app.CloseprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.CloseprojectMenu.Text = 'Close project';
            app.LoadAdddataButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.LoadAdddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAdddataButtonPushed, true);
            app.LoadAdddataButton.Text = 'Load/Add data';
            app.UITable = uitable(app.LifespanizerUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RunpreprocessingButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.RunpreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunpreprocessingButtonPushed, true);
            app.RunpreprocessingButton.Text = 'Run pre-processing';
            app.SelectRunclassifierButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.SelectRunclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRunclassifierButtonPushed, true);
            app.SelectRunclassifierButton.Text = 'Select/Run classifier';
            app.ExportPlotdataButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.ExportPlotdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotdataButtonPushed, true);
            app.ExportPlotdataButton.Text = 'Export/Plot data';
            app.Label = uilabel(app.LifespanizerUIFigure);
            % Show the figure after all components are created
            app.LifespanizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lifespan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifespanizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifespanizerUIFigure)
        end
    end
end