classdef processDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifydataUIFigure          matlab.ui.Figure
        ApplyselectedsettingstoallButton  matlab.ui.control.Button
        DeselectallButton             matlab.ui.control.Button
        SelectallButton               matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        ProcessselecteddataButton     matlab.ui.control.Button
        SelectpositionsandenterROIsarrayLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SelectprocessorDropDown       matlab.ui.control.DropDown
        SelectprocessorDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        
        function updateTable(app)
            
            str= app.SelectprocessorDropDown.Value;
            shallowObj=app.Data.shallowObj;
            t=app.UITable;
            t.ColumnName={'Select','Position','Position Name','Select ROIs array','Select frames'};
            t.ColumnEditable=[true false false true true];


            t.ColumnFormat={[] [] [] [] []};
            
            Data={};
            
            for i=1:numel(shallowObj.fov)


                if numel(shallowObj.fov(i).frames)>1
                     pix=find(matches(shallowObj.fov(i).channel,strcha));
                    fra=shallowObj.fov(i).frames(pix);
                else
                    fra=shallowObj.fov(i).frames;
                end
                
                Data(i,:)={true num2str(i) shallowObj.fov(i).id ['1:' num2str(numel(shallowObj.fov(i).roi))] ['1:' num2str(fra)]};
            end
            
            t.Data=Data;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj)
            app.Data.shallowObj=shallowObj;
            
            str={};
            for i=1:numel(shallowObj.processing.processor)
                str{i}=shallowObj.processing.processor(i).strid;
            end
            app.SelectprocessorDropDown.Items=str;
            
            updateTable(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ClassifydataUIFigure
        function ClassifydataUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SelectprocessorDropDown
        function SelectprocessorDropDownValueChanged(app, event)
            value = app.SelectprocessorDropDown.Value;
            
            shallowObj=app.Data.shallowObj;
            
          %  pix=find(contains(app.SelectprocessorDropDown.Items,value));
            
            updateTable(app)
            
%             dat=app.UITable.Data;
%             
%             dat{:,4}=num2str(shallowObj.processing.classification(pix).channel);
%             
%             app.UITable.Data=dat;
        end

        % Button pushed function: ProcessselecteddataButton
        function ProcessselecteddataButtonPushed(app, event)
             shallowObj=app.Data.shallowObj;
            
             list=app.SelectprocessorDropDown.Items;
             valueClassi=find(contains(list,app.SelectprocessorDropDown.Value));
             
             data=app.UITable.Data;
             
             selpos=find(cellfun(@(x) x==1,data(:,1)));
             
             roiscell={};
             frames={};
             
             for i=1:numel(selpos)
             roiscell{i}=str2num(data{selpos(i),4});

             frames{i}=str2num(data{selpos(i),5});
             end

           %  channel
          

            % roiscell
             d = uiprogressdlg(app.ClassifydataUIFigure,'Title','Please Wait...',...
                    'Message','Starting data classification...');
            d.Value=0.01;
            
   
             shallowObj.processData(valueClassi,'Fovs',selpos,'Rois',roiscell,'Progress',d,'Frames',frames);
             
           %  uialert(app.ClassifydataUIFigure,'Don t forget to save the project!!','Warning');
             
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            data=app.UITable.Data;
             
            data(:,1)={true};
             
             app.UITable.Data=data;
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
             data=app.UITable.Data;
             
            data(:,1)={false};
             
             app.UITable.Data=data;
        end

        % Button pushed function: ApplyselectedsettingstoallButton
        function ApplyselectedsettingstoallButtonPushed(app, event)
             data=app.UITable.Data;
             
             selectedfovs=find(cellfun(@(x) x==1,data(:,1)));
             
             if numel(selectedfovs)==0
                 uialert(app.ClassifydataUIFigure,'No position was selected','Error');
             end
             
             for i=1:size(data,1)
                 
                 if i~=selectedfovs(1)
                     
                     data(i,4:end)=data(selectedfovs(1),4:end);
                     
                 end
             end
             
             app.UITable.Data=data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifydataUIFigure = uifigure('Visible', 'off');
            app.ClassifydataUIFigure.Name = 'Classify data';
            app.ClassifydataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifydataUIFigureCloseRequest, true);
            app.SelectprocessorDropDownLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectprocessorDropDownLabel.Text = 'Select processor: ';
            app.SelectprocessorDropDown = uidropdown(app.ClassifydataUIFigure);
            app.SelectprocessorDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectprocessorDropDownValueChanged, true);
            app.UITable = uitable(app.ClassifydataUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectpositionsandenterROIsarrayLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectpositionsandenterROIsarrayLabel.Text = 'Select positions and enter ROIs array:';
            app.ProcessselecteddataButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ProcessselecteddataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessselecteddataButtonPushed, true);
            app.ProcessselecteddataButton.Text = 'Process selected data';
            app.CloseButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.ApplyselectedsettingstoallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ApplyselectedsettingstoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyselectedsettingstoallButtonPushed, true);
            app.ApplyselectedsettingstoallButton.Text = 'Apply selected settings to all';
            % Show the figure after all components are created
            app.ClassifydataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifydataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifydataUIFigure)
        end
    end
end