classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScoreAppUIFigure              matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        DisplayMenu                   matlab.ui.container.Menu
        ROIsMenu                      matlab.ui.container.Menu
        DisplaysettingsMenu           matlab.ui.container.Menu
        IntensityquantificationMenu   matlab.ui.container.Menu
        AnnotationMenu                matlab.ui.container.Menu
        DatasettingsMenu              matlab.ui.container.Menu
        DatabrowserMenu               matlab.ui.container.Menu
        IntensityQuantificationPanel  matlab.ui.container.Panel
        ShapeButton                   matlab.ui.control.StateButton
        LineIntensityprofileButton    matlab.ui.control.StateButton
        UIProfileAxes                 matlab.ui.control.UIAxes
        AnnotationPanel               matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        IndexChannelLabel             matlab.ui.control.Label
        MaskColorPicker               matlab.ui.control.ColorPicker
        MaskcolorColorPickerLabel     matlab.ui.control.Label
        Transparency                  matlab.ui.control.Slider
        TransparencyLabel             matlab.ui.control.Label
        UIAnnotationTable             matlab.ui.control.Table
        DataPanel                     matlab.ui.container.Panel
        UIDataAxes                    matlab.ui.control.UIAxes
        DataSettingsPanel             matlab.ui.container.Panel
        UIDataTable                   matlab.ui.control.Table
        ROisPanel                     matlab.ui.container.Panel
        UIROITable                    matlab.ui.control.Table
        DisplaysettingsPanel          matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        ChannelPanel                  matlab.ui.container.Panel
        LowHighDisplaySlider          matlab.ui.control.RangeSlider
        LevelsLabel                   matlab.ui.control.Label
        OverlayCheckBox               matlab.ui.control.CheckBox
        SelectedChannelLabel          matlab.ui.control.Label
        ChannelColorPicker            matlab.ui.control.ColorPicker
        WeightSlider                  matlab.ui.control.Slider
        WeightSliderLabel             matlab.ui.control.Label
        FrameEditField                matlab.ui.control.NumericEditField
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        ZoomSlider                    matlab.ui.control.Slider
        ZoomLabel                     matlab.ui.control.Label
        UIChannelTable                matlab.ui.control.Table
        UIDisplayAxes                 matlab.ui.control.UIAxes
        UIOverlayAxes                 matlab.ui.control.UIAxes
        UIImageAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        content = struct('ROIList',[]); % Description
        SliderTickLabels % Description
        HistogramEdges % Stocke les bords des bins de l'histogramme
        HistogramData  % Stocke les valeurs de l'histogramme pour chaque canal affiché
        HistogramChannels % Stocke les indices des channels affichés dans l'histogramme
        HistogramLines  % Stocke les handles des courbes pour éviter de les redessiner
        HistogramLimits % Stocke les handles des lignes de niveaux (low/high)
        LineIntensityProfileLine   % handle de l'objet imline (initialement [])
        LineProfilePosition        % position mémorisée de la ligne (par exemple, [x1 y1; x2 y2])
        EllipseIntensityProfileObj   % Handle de l'ellipse (initialement [])
        EllipseProfilePosition       % Position mémorisée (rectangle [x y width height])
        OriginalXLim
        OriginalYLim
        BaselineXLim   % Baseline X-limits à 100%
        BaselineYLim   % Baseline Y-limits à 100%
        PreviousZoomFactor
        DisplaySettings % Description
    end
    
    properties (Access = private)
        
    end

    methods (Access = private)

        % Toggle the visibility of a panel and update menu status
        function newVisibility = toggleVisibility(~, menuItem)
            if strcmp(menuItem.Checked, 'on')
                menuItem.Checked = 'off';
                newVisibility = 'off';
            else
                menuItem.Checked = 'on';
                newVisibility = 'on';
            end
        end

        function resizeUIImageAxes(app)
            % Position initiale et dimensions maximales
            baseX = 10;  % Coordonnée X de départ
            baseY = 10;  % Coordonnée Y de départ
            maxWidth = app.ScoreAppUIFigure.Position(3) - baseX;  % Largeur maximale
            maxHeight = app.ScoreAppUIFigure.Position(4) - baseY; % Hauteur maximale

            % Ajuster la taille en fonction de la visibilité des panneaux
            if strcmp(app.DisplaysettingsPanel.Visible, 'on') || strcmp(app.ROisPanel.Visible, 'on') 
                maxWidth = maxWidth - app.DisplaysettingsPanel.Position(3);
                baseX=baseX+app.DisplaysettingsPanel.Position(3);
            end

            if strcmp(app.DataPanel.Visible, 'on') || strcmp(app.DataSettingsPanel.Visible, 'on') || strcmp(app.AnnotationPanel.Visible, 'on')
                maxWidth = maxWidth - app.DataPanel.Position(3)-10;
            end

            % Mettre à jour la position et la taille de UIImageAxes
            app.UIImageAxes.Position = [baseX, baseY, maxWidth, maxHeight];

        
            % app.UIOverlayAxes = axes(app.ScoreAppUIFigure, 'Position', pos, ...
            %     'Color', 'none', 'XColor', 'none', 'YColor', 'none', ...
            %     'HitTest', 'off', 'Units', 'pixels');
            % % Lier les axes pour que leurs limites restent synchronisées.
             app.UIOverlayAxes.Position=app.UIImageAxes.Position;
             app.UIOverlayAxes.Color='none';
             app.UIOverlayAxes.XColor='none';
             app.UIOverlayAxes.YColor='none';
             app.UIOverlayAxes.HitTest='off';
             app.UIOverlayAxes.Units='pixels';
            linkaxes([app.UIImageAxes, app.UIOverlayAxes], 'xy');
        end

       function displayROIs(app)
    % Vérifie si des ROIs sont présentes
    if isempty(app.content.ROIList)
        app.UIROITable.Data = {}; % Vide la table si aucune ROI
        return;
    end

    % Préparer les colonnes : Display, Name, Size
    numROIs = numel(app.content.ROIList);
    tableData = cell(numROIs, 3);

    for i = 1:numROIs
        roi = app.content.ROIList{i}; % Récupérer l'instance de la ROI

        if i==numROIs
        tableData{i, 1} = true; % Désactiver l'affichage par défaut
        else
        tableData{i, 1} = false; % Désactiver l'affichage par défaut
        end

        tableData{i, 2} = roi.id; % Nom de la ROI
        tableData{i, 3} = sprintf('%dx%d', size(roi.image, 1), size(roi.image, 2)); % Taille de l'image
    end

    % Mettre à jour la table
    app.UIROITable.Data = tableData;
    app.UIROITable.ColumnName = {'Display', 'Name', 'Size'};
    
    % Ajuster les largeurs des colonnes
    app.UIROITable.ColumnWidth = {50, 'auto', 60};

    % Définir un callback pour gérer les sélections
    app.UIROITable.CellEditCallback = @(src, event) app.handleROITableEdit(event);

    displayROIChannels(app);
    score_display(app, 'slow');

end

function handleROITableEdit(app, event)
    % Callback pour s'assurer qu'une seule ROI est sélectionnée
    if event.Indices(2) == 1 % Si la colonne "Display" est modifiée
        % Désactiver toutes les cases sauf celle cliquée
        numROIs = size(app.UIROITable.Data, 1);
        for i = 1:numROIs
            app.UIROITable.Data{i, 1} = false;
        end

        % Activer uniquement la ligne sélectionnée
        app.UIROITable.Data{event.Indices(1), 1} = true;

        % Mettre à jour la table
        app.UIROITable.Data = app.UIROITable.Data;

        % Optionnel : effectuer des actions basées sur la sélection
        selectedROI = app.content.ROIList{event.Indices(1)};
        disp(['Selected ROI: ', selectedROI.id]);

         displayROIChannels(app);

         numFrames = size(selectedROI.image, 4);

    % Mettre à jour les limites du slider
    app.FrameSlider.Limits = [1 numFrames];
    
    % Réinitialiser la valeur du slider (optionnel)
    app.FrameSlider.Value = selectedROI.display.frame;

         score_display(app, 'slow');
    end
end
        
function displayROIChannels(app)
    % Vérifier si des ROIs sont présentes dans l'application
    if isempty(app.content.ROIList)
        app.UIChannelTable.Data = {}; % Vide la table si aucune ROI
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1); % Cherche la première ROI cochée
    if isempty(selectedIndex)
        app.UIChannelTable.Data = {}; % Vide la table si aucune ROI n'est sélectionnée
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedIndex};

    % Vérifier si la ROI a des canaux disponibles
    if isempty(selectedROI.display.channel)
        app.UIChannelTable.Data = {}; % Vide la table si aucun canal
        return;
    end

    % Préparer la liste des canaux affichables : 
    % On inclut les canaux dont l'intensité n'est pas nulle et aussi ceux qui correspondent à des images RGB (3 canaux)
    numChannels = numel(selectedROI.display.channel);
    colorChannels = [];
    indexedChannels=[];

    for i = 1:numChannels
        % Vérifier si le canal correspond à une image RGB
        pix = selectedROI.findChannelID(selectedROI.display.channel{i});
        if numel(pix)==3
            % Canal RGB détecté, on l'inclut
            colorChannels = [colorChannels i];
        else
            % Pour les autres canaux, on ne les inclut que si l'intensité n'est pas nulle
            if sum(selectedROI.display.intensity(i, :)) == 0
               indexedChannels=[ indexedChannels i]; 
              % continue;
                
            else
            colorChannels = [colorChannels i];
            end
        end
    end

    % Préparer les colonnes pour la table : Display, Name, Levels, RGB, Weight, Auto
    tableData = cell(numel(colorChannels), 6);
    for i = 1:numel(colorChannels)
        chIndex = colorChannels(i);
        % Colonne "Display" : checkbox indiquant si le canal doit être affiché
        tableData{i, 1} = logical(selectedROI.display.selectedchannel(chIndex));
        % Colonne "Name" : nom du canal
        tableData{i, 2} = selectedROI.display.channel{chIndex};
        % Colonne "Levels" : niveaux d'affichage (convertis en échelle 0-65535)

        if ~isfield(selectedROI.display,'displaylim') | numel(selectedROI.display.displaylim)==0
             selectedROI.computeDisplaylim;
        end

        tableData{i, 3} = sprintf('%.0f %.0f', round(65535 * selectedROI.display.displaylim(1, chIndex)), round(65535 * selectedROI.display.displaylim(2, chIndex)));
        % Colonne "RGB" : affichage de la couleur (pour les images RGB, vous pouvez afficher par exemple 'RGB' ou la valeur si définie)
        if numel(selectedROI.findChannelID(selectedROI.display.channel{chIndex}))==3
            % Optionnel : vous pouvez afficher "RGB" ou une valeur par défaut
            tableData{i, 4} = 'RGB';
        else
            tableData{i, 4} = sprintf('%.1f %.1f %.1f', selectedROI.display.rgb(chIndex, :));
        end
        % Colonne "Weight" : poids du canal
        tableData{i, 5} = sprintf('%.1f', selectedROI.display.intensity(chIndex, 1));
        % Colonne "Auto" : par défaut, "Auto" est désactivé
        tableData{i, 6} = false;
    end

    % Mettre à jour la table et ses propriétés
    app.UIChannelTable.Data = tableData;
    app.UIChannelTable.ColumnName = {'Display', 'Name', 'Levels', 'RGB', 'Weight', 'Auto'};
    app.UIChannelTable.ColumnWidth = {40, 100, 80, 80, 60, 45};
    % Rendre certaines colonnes éditables (ici Display, Levels, RGB, Weight, Auto)
    app.UIChannelTable.ColumnEditable = [true, false, true, true, true, true];

    % Définir le callback pour gérer les modifications
    app.UIChannelTable.CellEditCallback = @(src, event) app.handleChannelTableEdit(event, selectedROI, colorChannels);


     nIndexed = numel(indexedChannels);
    tableDataIndex = cell(nIndexed, 3);  % colonnes: Display, Name, No de la classe
    for i = 1:nIndexed
        chIndex = indexedChannels(i);
        % Colonne "Display": case à cocher (initialement false)
        tableDataIndex{i, 1} = logical(selectedROI.display.selectedchannel(chIndex));
        %tableDataIndex{i, 1} = false;
        % Colonne "Name": le nom du canal
        tableDataIndex{i, 2} = selectedROI.display.channel{chIndex};
        % Colonne "No de la classe": on essaie d'utiliser une propriété 'classNum' si elle existe
        if isfield(selectedROI.display, 'classNum')
            tableDataIndex{i, 3} = selectedROI.display.classNum(chIndex);
        else
            tableDataIndex{i, 3} = chIndex;
        end
    end

    % Mettre à jour UIAnnotationTable
    app.UIAnnotationTable.Data = tableDataIndex;
    app.UIAnnotationTable.ColumnName = {'Display', 'Name', 'No de la classe'};
    app.UIAnnotationTable.ColumnWidth = {50, 'auto', 50};
    % Rendre la colonne "Display" éditable
    app.UIAnnotationTable.ColumnEditable = [true, false, false];

    app.UIAnnotationTable.CellEditCallback = @(src, event) app.handleIndexedChannelTableEdit(event, selectedROI, indexedChannels);


    % Mettre à jour l'affichage des features du channel et redessiner l'image si besoin
    displayChannelFeatures(app);
    score_display(app, 'slow');
end


function handleChannelTableEdit(app, event, selectedROI, colorChannels)
    % Callback pour gérer l'édition de la table des canaux
    rowIndex = event.Indices(1); % Index de la ligne modifiée
    colIndex = event.Indices(2); % Index de la colonne modifiée
    chIndex = colorChannels(rowIndex); % Trouver le bon index du channel

    switch colIndex
        case 1 % Modification dans la colonne "Display" (checkbox)
            selectedROI.display.selectedchannel(chIndex) = event.NewData;

        case 3 
            tmp=str2num(event.NewData);
            selectedROI.display.displaylim(:, chIndex) = double(tmp/65535);
            

        case 4 % Modification dans la colonne "RGB"
            % Valider l'entrée comme un triplet RGB
         rgbValues = str2double(strsplit(event.NewData, {' ', ','}));

        if numel(rgbValues) == 3 && all(~isnan(rgbValues))
               disp('RGB values parsed successfully');
        else
                disp('Invalid RGB input format');
        end

            if numel(rgbValues) == 3 && all(rgbValues >= 0 & rgbValues <= 1)
                selectedROI.display.rgb(chIndex, :) = rgbValues';
            else
                uialert(app.ScoreAppUIFigure, 'RGB values must be three numbers between 0 and 1.', 'Invalid Input');
                app.displayROIChannels(); % Réinitialiser la table
            end

        case 5 % Modification dans la colonne "Weight"
            % Valider l'entrée comme un nombre positif
            weightValue = str2double(event.NewData);
            if ~isnan(weightValue) && weightValue >= 0
                selectedROI.display.intensity(chIndex) = min(1,max(0.01,weightValue));

            else
                uialert(app.ScoreAppUIFigure, 'Weight must be a positive number.', 'Invalid Input');
                app.displayROIChannels(); % Réinitialiser la table
            end

        case 6 % Modification dans la colonne "Auto" (checkbox pour auto-adjust)
            if event.NewData
                % Appliquer un auto-ajustement des niveaux
                selectedROI.computeDisplaylim;
                app.displayROIChannels(); % Mettre à jour la table
            end
    end

    displayChannelFeatures(app);
     score_display(app, 'refresh');
    % event=[];
    % event.Value= log10(selectedROI.display.displaylim(1, chIndex));
    % LowDisplaySliderValueChanging(app, event);
    % event.Value= log10(selectedROI.display.displaylim(2, chIndex));
    % HighDisplaySliderValueChanging(app, event);
end


 function displayChannelFeatures(app)
    % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return; % Pas de ROI, rien à afficher
    end

    % Trouver la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return; % Pas de ROI sélectionnée, quitter
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné dans la table des canaux
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return; % Pas de canal sélectionné, quitter
    end

    %channelIndex = selectedChannelIndex(1); % Index du canal sélectionné
        % Trouver le bon index du canal basé sur son nom dans `selectedROI.display.channel`
    selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex, 2}; % Nom du canal sélectionné
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);


    % Définir les bornes des sliders en échelle logarithmique
    minVal = 1; % On évite 0 car log(0) est indéfini
    maxVal = 65535;
    logMin = log10(minVal);
    logMax = log10(maxVal);

    % Mettre à jour les limites des sliders
    app.LowHighDisplaySlider.Limits = [logMin, logMax];

    % Appliquer la transformation logarithmique pour récupérer la position correcte
    lowLevel = max(65535*selectedROI.display.displaylim(1, channelIndex), 1);
    highLevel = min(65535*selectedROI.display.displaylim(2, channelIndex), 65535);
    
    app.LowHighDisplaySlider.Value = [log10(lowLevel) log10(highLevel)];

    % Mettre à jour le color picker RGB
    rgbValues = selectedROI.display.rgb(channelIndex, :);
    app.ChannelColorPicker.Value = rgbValues;

    weightValue = min(1,max(0,selectedROI.display.intensity(channelIndex, 1))); % Normalisé entre 0 et 1
    app.WeightSlider.Value = weightValue;

    % Configurer les callbacks MATLAB natifs
    %app.LowDisplaySlider.ValueChangingFcn = @(src, event) app.LowDisplaySliderValueChanging(event, channelIndex);
    %app.HighDisplaySlider.ValueChangingFcn = @(src, event) app.HighDisplaySliderValueChanging(event, channelIndex);

    % Configurer le color picker pour mettre à jour les valeurs RGB
    %app.ChannelColorPicker.ValueChangedFcn = @(src, event) app.updateRGB(channelIndex);
end

function updateChannelTable(app, selectedROI)
    % Vérifier si la table est vide ou si aucun canal n'est affiché
    if isempty(app.UIChannelTable.Data)
        return;
    end

    % Trouver les canaux à afficher (colorChannels)
    numChannels = size(selectedROI.display.rgb, 1);
    colorChannels = [];

    for i = 1:numChannels
        if sum(selectedROI.display.intensity(i, :)) == 0 
            continue; % Skip les canaux indexés (intensity == 0)
        end
        colorChannels = [colorChannels, i]; % Stocke les indices des canaux affichables
    end

    % Vérifier qu'on a bien des canaux valides
    if isempty(colorChannels)
        app.UIChannelTable.Data = {}; % Vide la table si aucun canal valide
        return;
    end

    % Récupérer les données actuelles de la table
    tableData = app.UIChannelTable.Data;
    
    for i = 1:numel(colorChannels)
        chanIndex = colorChannels(i); % Indice réel du canal dans `selectedROI.display`
        
        % Mettre à jour la colonne "Levels" (conversion en 65535)
        tableData{i, 3} = sprintf('%.2f %.2f', ...
            round(65535 * selectedROI.display.displaylim(1, chanIndex)), ...
            round(65535 * selectedROI.display.displaylim(2, chanIndex)));
        
        % Mettre à jour la colonne "RGB" (sans crochets)
        tableData{i, 4} = sprintf('%.2f %.2f %.2f', selectedROI.display.rgb(chanIndex, :));
        
        % Mettre à jour la colonne "Weight"
        tableData{i, 5} = sprintf('%.2f', selectedROI.display.intensity(chanIndex, 1));
    end

    % Mettre à jour les données de la table
    app.UIChannelTable.Data = tableData;
    score_display(app, 'refresh');
end
        
function handleIndexedChannelTableEdit(app, event, selectedROI, indexedChannels)
             % Récupérer l'indice de la ligne modifiée et de la colonne modifiée
    rowIndex = event.Indices(1); % ligne modifiée dans la table
    colIndex = event.Indices(2); % colonne modifiée dans la table
    
    % Récupérer l'indice réel du canal indexé correspondant à cette ligne
    chIndex = indexedChannels(rowIndex);
    
    % Selon la colonne éditée
    switch colIndex
        case 1
            % Colonne "Display" : mettre à jour la sélection du canal
            selectedROI.display.selectedchannel(chIndex) = event.NewData;
            
        case 3
            % Colonne "No de la classe" : convertir la nouvelle donnée en numérique
            newClass = str2double(event.NewData);
            if ~isnan(newClass)
                % Vous pouvez ajouter ici des vérifications supplémentaires (par exemple, vérifier s'il s'agit d'un entier)
                % Mettre à jour la classe du canal dans selectedROI.display.classNum (ou autre propriété appropriée)
                selectedROI.display.classNum(chIndex) = newClass;
            else
                uialert(app.ScoreAppUIFigure, 'Class number must be numeric.', 'Invalid Input');
                % (Optionnel) Vous pouvez rafraîchir la table pour réafficher la valeur précédente
            end
            
        otherwise
            % Pour la colonne "Name", on ne fait rien (elle n'est pas éditable)
    end

    % Mise à jour des affichages dépendants
    displayChannelFeatures(app);
    score_display(app, 'refresh');
        end
    end

    methods (Access = public)
        function addROI(app,roiobj)
            % Vérifier si ROI existe déjà dans app.content.ROIList
            if isempty(app.content.ROIList)
                isPresent = false;
            else
                % Vérifier si une ROI avec le même ID existe
                isPresent = any(cellfun(@(roi) strcmp(roi.id, roiobj.id), app.content.ROIList));
            end

            if isPresent
                disp(['ROI with ID "', roiobj.id, '" is already present. Skipping addition.']);
            else
                % Ajouter la ROI à la liste
                if numel(roiobj.image)==0
                    roiobj.load;
                end

                app.content.ROIList{end+1} = roiobj;
                disp(['Adding ROI: ', roiobj.id]);

                % Mettre à jour l'affichage de la table
                app.displayROIs();
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roiobj)
            % Connecter les callbacks des menus
            % app.DisplaysettingsMenu.MenuSelectedFcn = @app.DisplaysettingsMenuSelected;
            % app.ROIsMenu.MenuSelectedFcn = @app.ROIsMenuSelected;
            % app.DatasettingsMenu.MenuSelectedFcn = @app.DatasettingsMenuSelected;
            % app.DatabrowserMenu.MenuSelectedFcn = @app.DataMenuSelected;

            app.addROI(roiobj);
            % Initialiser l'état des panneaux
            app.DisplaysettingsPanel.Visible = 'on';
            app.ROisPanel.Visible = 'on';
            app.DataSettingsPanel.Visible = 'off';
            app.DataPanel.Visible = 'off';
            app.resizeUIImageAxes();

        end

        % Callback function
        function DisplaysettingsMenuSelected(app, event)

        end

        % Callback function
        function ROIsMenuSelected(app, event)

        end

        % Callback function
        function DatasettingsMenuSelected(app, event)

        end

        % Callback function
        function DataMenuSelected(app, event)

        end

        % Callback function
        function DisplaysettingsMenuSelected2(app, event)

        end

        % Menu selected function: ROIsMenu
        function ROIsMenuSelected2(app, event)
            app.ROisPanel.Visible = app.toggleVisibility(app.ROIsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DatasettingsMenu
        function DatasettingsMenuSelected2(app, event)
            app.DataSettingsPanel.Visible = app.toggleVisibility(app.DatasettingsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DatabrowserMenu
        function DatabrowserMenuSelected2(app, event)
            app.DataPanel.Visible = app.toggleVisibility(app.DatabrowserMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DisplaysettingsMenu
        function DisplaysettingsMenuSelected3(app, event)
            app.DisplaysettingsPanel.Visible = app.toggleVisibility(app.DisplaysettingsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Selection changed function: UIChannelTable
        function UIChannelTableSelectionChanged(app, event)
            selection = app.UIChannelTable.Selection;
             % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        app.SelectedChannelLabel.Text = 'Selected Channel: None';
        return;
    end

    % Trouver la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        app.SelectedChannelLabel.Text = 'Selected Channel: None';
        return;
    end

    % Vérifier si un canal est sélectionné dans la table
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        app.SelectedChannelLabel.Text = 'Selected Channel: None';
        return;
    end

    % Récupérer le nom du canal sélectionné
    selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2};

    % Mettre à jour le label avec le nom du canal sélectionné
    app.SelectedChannelLabel.Text = sprintf('Selected Channel: %s', selectedChannelName);

    % Mettre à jour les sliders et le color picker pour refléter les paramètres du canal
    displayChannelFeatures(app);
            displayChannelFeatures(app);
        end

        % Callback function
        function LowDisplaySliderValueChanging(app, event)
   %          changingValue = event.Value;
   %          % Vérifie si une ROI est sélectionnée
   %  selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
   %  if isempty(selectedROIIndex)
   %      return;
   %  end
   % 
   %  % Récupère la ROI sélectionnée
   %  selectedROI = app.content.ROIList{selectedROIIndex};
   % 
   %  % Vérifie si un canal est sélectionné
   %  if isempty(app.UIChannelTable.Selection)
   %      return;
   %  end
   % 
   %  % Récupère l'index du canal sélectionné
   %  channelIndex = app.UIChannelTable.Selection(1);
   %  if isempty(channelIndex)
   %      return;
   %  end
   % 
   %  selectedChannelName = app.UIChannelTable.Data{channelIndex, 2}; % Nom du canal sélectionné
   %  channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
   % 
   %  % Met à jour la limite basse en échelle linéaire
   %  selectedROI.display.displaylim(1, channelIndex) = 10^(event.Value)/65535;
   % 
   %  % Mise à jour dynamique de la table
   %  updateChannelTable(app, selectedROI);
   % 
   %  % Met à jour l'affichage de l'image en direct
   % % updateImageDisplay(app);
   % 
        end

        % Callback function
        function HighDisplaySliderValueChanging(app, event)
    %         changingValue = event.Value;
    %          % Vérifie si une ROI est sélectionnée
    % selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    % if isempty(selectedROIIndex)
    %     return;
    % end
    % 
    % % Récupère la ROI sélectionnée
    % selectedROI = app.content.ROIList{selectedROIIndex};
    % 
    % % Vérifie si un canal est sélectionné
    % if isempty(app.UIChannelTable.Selection)
    %     return;
    % end
    % 
    % % Récupère l'index du canal sélectionné
    % channelIndex = app.UIChannelTable.Selection(1);
    % if isempty(channelIndex)
    %     return;
    % end
    % 
    % selectedChannelName = app.UIChannelTable.Data{channelIndex, 2}; % Nom du canal sélectionné
    % channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
    % 
    % % Met à jour la limite haute en échelle linéaire
    % selectedROI.display.displaylim(2, channelIndex) = 10^(event.Value)/65535;
    % 
    % updateChannelTable(app, selectedROI);
    % 
    % % Met à jour l'affichage de l'image en direct
    % %updateImageDisplay(app);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)

 % Récupérer la valeur actuelle du slider depuis l'événement (en pourcentage : 100 à 500)
    zoomPercent = event.Value;  
    zoomFactor = zoomPercent / 100;  % 1 pour 100%, 5 pour 500%
    app.PreviousZoomFactor=zoomFactor;

    % Si le slider est exactement à 100, on réinitialise la baseline
    if abs(zoomPercent - 100) < 1e-3
        app.OriginalXLim = app.UIImageAxes.XLim;
        app.OriginalYLim = app.UIImageAxes.YLim;
    end

    % Si la baseline n'est pas encore définie, l'initialiser
    if isempty(app.OriginalXLim) || isempty(app.OriginalYLim)
        app.OriginalXLim = app.UIImageAxes.XLim;
        app.OriginalYLim = app.UIImageAxes.YLim;
    end

    % Toujours utiliser le centre actuel de l'axe pour le zoom "sur place"
    center = [mean(app.UIImageAxes.XLim), mean(app.UIImageAxes.YLim)];
    
    % Définir la dimension de référence (baseline) en se basant sur la vue à 100%
    baselineWidth  = app.OriginalXLim(2) - app.OriginalXLim(1);
    baselineHeight = app.OriginalYLim(2) - app.OriginalYLim(1);

    % Calculer la nouvelle largeur et hauteur selon le facteur de zoom
    newWidth  = baselineWidth / zoomFactor;
    newHeight = baselineHeight / zoomFactor;

    % Calculer les nouvelles limites en centrant la vue autour du centre actuel
    newXLim = [center(1) - newWidth/2, center(1) + newWidth/2];
    newYLim = [center(2) - newHeight/2, center(2) + newHeight/2];

    % Mettre à jour les limites de l'axe de l'image
    set(app.UIImageAxes, 'XLim', newXLim, 'YLim', newYLim);

    % Gérer le mode Pan : désactiver si zoomFactor est 1, sinon activer
    % if zoomFactor == 1
    %     pan(app.ScoreAppUIFigure, 'off');
    % else
    %     pan(app.ScoreAppUIFigure, 'on');
    % end

        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            
    % Récupérer la nouvelle valeur du slider et l'arrondir
    newFrame = round(event.Value);

    % Vérifier qu'une ROI est présente
    if isempty(app.content.ROIList)
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée (celle cochée dans la table)
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Obtenir le nombre total de frames pour cette ROI
    numFrames = size(selectedROI.image, 4);
    % S'assurer que newFrame est dans l'intervalle valide
    if newFrame < 1
        newFrame = 1;
    elseif newFrame > numFrames
        newFrame = numFrames;
    end

    % Mettre à jour le numéro de frame dans la ROI
    selectedROI.display.frame = newFrame;
    app.FrameEditField.Value=selectedROI.display.frame;

    % Mettre à jour l'affichage (image et histogramme) en mode "refresh"
    score_display(app, 'refresh');

        end

        % Value changing function: WeightSlider
        function WeightSliderValueChanging(app, event)
            changingValue = event.Value;
            
              % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return;
    end

    % Trouver l'index réel du canal dans colorChannels
    channelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2}; % Nom du canal
    channelIndex = find(strcmp(selectedROI.display.channel, channelName), 1);

    if isempty(channelIndex)
        return;
    end

    % Mettre à jour la valeur du poids dans selectedROI
    newWeight = max(0.01,event.Value); % Récupérer la nouvelle valeur du slider
    selectedROI.display.intensity(channelIndex, 1) = newWeight;

    % Mettre à jour la colonne "Weight" dans la table des channels
    updateChannelTable(app, selectedROI);
   % app.UIChannelTable.Data{selectedChannelIndex(1), 5} = sprintf('%.2f', newWeight);

        end

        % Callback function: ChannelColorPicker
        function ChannelColorPickerValueChanged(app, event)
            value = app.ChannelColorPicker.Value;
               % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné dans la table des canaux
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return;
    end

    % Trouver l'index réel du canal dans colorChannels
    channelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2}; % Nom du canal
    channelIndex = find(strcmp(selectedROI.display.channel, channelName), 1);

    if isempty(channelIndex)
        return;
    end

    % Récupérer la nouvelle couleur du color picker
    newRGB = event.Value;

    % Mettre à jour la couleur RGB dans selectedROI
    selectedROI.display.rgb(channelIndex, :) = newRGB;

    % Mettre à jour la colonne "RGB" dans la table des canaux
    updateChannelTable(app, selectedROI);
    %app.UIChannelTable.Data{selectedChannelIndex(1), 4} = sprintf('%.2f %.2f %.2f', newRGB);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            
             % Récupérer la nouvelle valeur entrée par l'utilisateur et l'arrondir
    newFrame = round(app.FrameEditField.Value);
    
    % Vérifier qu'une ROI est présente
    if isempty(app.content.ROIList)
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Déterminer le nombre de frames disponibles
    numFrames = size(selectedROI.image, 4);
    
    % Contraindre newFrame à l'intervalle [1, numFrames]
    if newFrame < 1
        newFrame = 1;
    elseif newFrame > numFrames
        newFrame = numFrames;
    end
    
    % Mettre à jour le slider et la propriété de la ROI
    app.FrameSlider.Value = newFrame;
    selectedROI.display.frame = newFrame;
    
    % Mettre à jour le champ d'édition (au cas où il ait été ajusté)
    app.FrameEditField.Value = newFrame;
    
    % Rafraîchir l'affichage (image et histogramme)
    score_display(app, 'refresh');
        end

        % Key press function: ScoreAppUIFigure
        function ScoreAppUIFigureKeyPress(app, event)
            
            % Si aucune ROI n'est présente, ne rien faire
    if isempty(app.content.ROIList)
        return;
    end
    
    % Récupérer l'index de la ROI sélectionnée (celle cochée dans la table)
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Déterminer l'incrément selon la touche fléchée pressée
    switch event.Key
        case 'rightarrow'
            increment = 1;
        case 'leftarrow'
            increment = -1;
        otherwise
            return; % Ignorer d'autres touches
    end
    
    % Récupérer la frame courante et le nombre total de frames
    currentFrame = selectedROI.display.frame;
    numFrames = size(selectedROI.image, 4);
    
    % Calculer la nouvelle frame en s'assurant qu'elle reste dans l'intervalle [1, numFrames]
    newFrame = currentFrame + increment;
    newFrame = max(1, min(newFrame, numFrames));
    
    % Mettre à jour la propriété de la ROI, le slider et le champ d'édition
    selectedROI.display.frame = newFrame;
    app.FrameSlider.Value = newFrame;
    app.FrameEditField.Value = newFrame;
    
    % Rafraîchir l'affichage (image et histogramme)
    score_display(app, 'refresh');
        end

        % Value changed function: OverlayCheckBox
        function OverlayCheckBoxValueChanged(app, event)
            value = app.OverlayCheckBox.Value;
            app.LineIntensityprofileButton.Value=false;
            app.ShapeButton.Value=false;
            LineIntensityprofileButtonValueChanged2(app, event);
            ShapeButtonValueChanged(app,event);
            score_display(app, 'slow');
        end

        % Callback function
        function LineIntensityprofileButtonValueChanged(app, event)


        end

        % Value changed function: LineIntensityprofileButton
        function LineIntensityprofileButtonValueChanged2(app, event)
            value = app.LineIntensityprofileButton.Value;

         if app.LineIntensityprofileButton.Value
          % Désactiver le mode ellipse
            app.ShapeButton.Value = false;
            ShapeButtonValueChanged(app, []);

        % Bouton activé : afficher la ligne de profil
        if ~isempty(app.LineIntensityProfileLine) && isvalid(app.LineIntensityProfileLine)
            % La ligne existe déjà ; vous pouvez éventuellement la laisser telle quelle
            % ou la mettre au premier plan (par exemple, en la recréant si nécessaire)
        else
            % Déterminer la position initiale de la ligne
            if ~isempty(app.LineProfilePosition)
                pos = app.LineProfilePosition;  % Utiliser la position mémorisée
            else
                % Position par défaut : ligne horizontale au milieu de l'axe de l'image
                xLimits = app.UIImageAxes.XLim;
                yLimits = app.UIImageAxes.YLim;
                pos = [xLimits(1)+10, mean(yLimits); xLimits(2)-10, mean(yLimits)];
            end
            % Créer la ligne sur l'axe de l'image
            app.LineIntensityProfileLine = imline(app.UIImageAxes, pos);
            % Ajouter un callback pour mettre à jour le profil quand la ligne est déplacée
            addNewPositionCallback(app.LineIntensityProfileLine, @(newPos) score_updateIntensityProfile(app, newPos));
        end
        % Mise à jour immédiate du profil d'intensité avec la position actuelle de la ligne
        score_updateIntensityProfile(app, getPosition(app.LineIntensityProfileLine));
    else
        % Bouton désactivé : mémoriser la position et supprimer la ligne
        if ~isempty(app.LineIntensityProfileLine) && isvalid(app.LineIntensityProfileLine)
            app.LineProfilePosition = getPosition(app.LineIntensityProfileLine);
            delete(app.LineIntensityProfileLine);  % Supprime la ligne
            app.LineIntensityProfileLine = [];
        end
        % Effacer le plot du profil d'intensité
        cla(app.UIProfileAxes);
    end


        end

        % Value changed function: ShapeButton
        function ShapeButtonValueChanged(app, event)
            value = app.ShapeButton.Value;
        
          if value
        app.LineIntensityprofileButton.Value = false;
        LineIntensityprofileButtonValueChanged2(app, []);

        % Bouton activé : afficher ou créer l'ellipse de profil
        if isempty(app.EllipseIntensityProfileObj) || ~isvalid(app.EllipseIntensityProfileObj)
            % Déterminer la position initiale de l'ellipse
            if ~isempty(app.EllipseProfilePosition)
                % Utiliser la position mémorisée (format [x y width height])
                pos = app.EllipseProfilePosition;
                center = [pos(1) + pos(3)/2, pos(2) + pos(4)/2];
                semiAxes = [pos(3)/2, pos(4)/2];
            else
                % Position par défaut : ellipse centrée dans l'axe de l'image
                xLimits = app.UIImageAxes.XLim;
                yLimits = app.UIImageAxes.YLim;
                width  = (xLimits(2)-xLimits(1)) * 0.5;
                height = (yLimits(2)-yLimits(1)) * 0.5;
                center = [mean(xLimits), mean(yLimits)];
                semiAxes = [width/4, height/4];
            end
            % Créer l'ellipse en spécifiant 'Center' et 'SemiAxes'
            app.EllipseIntensityProfileObj = drawellipse(app.UIImageAxes, ...
                'Center', center, 'SemiAxes', semiAxes);
            % Ajouter des listeners pour mettre à jour le profil en temps réel
            addlistener(app.EllipseIntensityProfileObj, 'MovingROI', @(src,evt) score_updateEllipticalProfile(app, src));
            addlistener(app.EllipseIntensityProfileObj, 'ROIMoved', @(src,evt) score_updateEllipticalProfile(app, src));
        end
        % Mise à jour immédiate du profil avec l'ellipse actuelle
        score_updateEllipticalProfile(app, app.EllipseIntensityProfileObj);
    else
        % Bouton désactivé : mémoriser la position et supprimer l'ellipse
        if ~isempty(app.EllipseIntensityProfileObj) && isvalid(app.EllipseIntensityProfileObj)
            % Pour récupérer la position, on utilise les propriétés 'Center' et 'SemiAxes'
            center = app.EllipseIntensityProfileObj.Center;
            semiAxes = app.EllipseIntensityProfileObj.SemiAxes;
            app.EllipseProfilePosition = [center(1)-semiAxes(1), center(2)-semiAxes(2), 2*semiAxes(1), 2*semiAxes(2)];
            delete(app.EllipseIntensityProfileObj);
            app.EllipseIntensityProfileObj = [];
        end
        % Effacer le plot du profil
        cla(app.UIProfileAxes);
    
          end
        

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
 % Récupérer la ROI actuellement sélectionnée via la table des ROIs
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Récupérer la taille de l'image brute
    imgSize = size(selectedROI.image);  % [height, width, channels, frames]
    imgHeight = imgSize(1);
    imgWidth  = imgSize(2);
    
    % Vérifier si l'overlay est actif
    if app.OverlayCheckBox.Value
        % En mode overlay, on affiche l'image composite qui occupe la taille de l'image
        newOrigXLim = [1, imgWidth];
        newOrigYLim = [1, imgHeight];
    else
        % En mode non-overlay, l'image de chaque canal est affichée empilée verticalement.
        % Déterminer le nombre de canaux affichés via la table (colonne 1)
        if isempty(app.UIChannelTable.Data)
            newOrigXLim = [1, imgWidth];
            newOrigYLim = [1, imgHeight];
        else
            checkboxValues = cell2mat(app.UIChannelTable.Data(:,1));
            numDisplayed = sum(checkboxValues);
            if numDisplayed < 1
                numDisplayed = 1;
            end
            newOrigXLim = [1, imgWidth];
            newOrigYLim = [1, numDisplayed * imgHeight];
        end
    end
    
    % Réinitialiser l'axe UIImageAxes aux nouveaux paramètres
    set(app.UIImageAxes, 'XLim', newOrigXLim, 'YLim', newOrigYLim);
    
    % Mettre à jour les propriétés de référence du zoom
    app.OriginalXLim = newOrigXLim;
    app.OriginalYLim = newOrigYLim;
    app.PreviousZoomFactor = 1;
    
    % Réinitialiser le ZoomSlider à 100%
    app.ZoomSlider.Value = 100;
    
    % Désactiver le mode Pan
    pan(app.ScoreAppUIFigure, 'off');
    
    % Optionnel : si vous utilisez un bouton "Restore View" intégré à l'axe,
    % vous pouvez également réinitialiser son état ici.

        end

        % Callback function
        function PanOFFButtonValueChanged(app, event)
            value = app.PanOFFButton.Value;
       
            if value & app.PreviousZoomFactor>1
 pan(app.ScoreAppUIFigure, 'on');
 app.PanOFFButton.Text='Pan ON';
 app.PanOFFButton.FontColor=[1 0 0];
            else
 pan(app.ScoreAppUIFigure, 'off');
  app.PanOFFButton.Text='Pan OFF';
  app.PanOFFButton.FontColor=[0 0 0];
            end
        end

        % Value changing function: LowHighDisplaySlider
        function LowHighDisplaySliderValueChanging(app, event)
            changingValue = event.Value;
             % event.Value est un vecteur à 2 éléments : [lowVal, highVal] (en échelle logarithmique)
    lowLogVal = event.Value(1);
    highLogVal = event.Value(2);
    
    % Convertir en valeurs linéaires en tenant compte que l'échelle est basée sur 65535
    newLow = 10^(lowLogVal) / 65535;
    newHigh = 10^(highLogVal) / 65535;
    
    % Récupérer la ROI et le canal sélectionné
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Vérifier que l'utilisateur a sélectionné un canal dans la table des channels
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return;
    end
    selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2};
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
    if isempty(channelIndex)
        return;
    end
    
    % Mettre à jour la propriété displaylim pour ce canal avec les nouvelles valeurs
    selectedROI.display.displaylim(:, channelIndex) = [newLow; newHigh];
    
    % Mettre à jour la table des canaux (pour refléter les nouvelles valeurs)
    updateChannelTable(app, selectedROI);
    
    % Forcer la mise à jour de l'affichage (image et histogramme)
    score_display(app, 'refresh');
        end

        % Selection changed function: UIAnnotationTable
        function UIAnnotationTableSelectionChanged(app, event)
            selection = app.UIAnnotationTable.Selection;

             % Vérifier qu'une ROI est sélectionnée via la table des ROIs
    if isempty(app.content.ROIList)
        return;
    end
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Vérifier qu'une ligne est sélectionnée dans UIAnnotationTable
    selectedRow = app.UIAnnotationTable.Selection;
    if isempty(selectedRow) || isempty(selectedRow(1))
        return;
    end
    
    % Récupérer le nom du canal indexé sélectionné (colonne 2)
    selectedChannelName = app.UIAnnotationTable.Data{selectedRow(1), 2};
    % Trouver l'indice réel de ce canal dans selectedROI.display.channel
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
    if isempty(channelIndex)
        return;
    end

  
    % Mettre à jour le MaskColorPicker avec la couleur associée dans selectedROI.display.rgb
    app.MaskColorPicker.Value = selectedROI.display.rgb(channelIndex, :);

    selectedChannelName = app.UIAnnotationTable.Data{selectedRow(1), 2};
    
    % Mettre à jour le label avec le nom du canal sélectionné
    app.IndexChannelLabel.Text = sprintf('Indexed Channel: %s', selectedChannelName);

    score_display(app,'refresh');
    
        end

        % Callback function: MaskColorPicker
        function MaskColorPickerValueChanged(app, event)
            value = app.MaskColorPicker.Value;

             newColor = event.Value;  % La nouvelle couleur sélectionnée, par exemple [R G B]
    
    % Vérifier qu'une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return;
    end
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:,1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Vérifier qu'une ligne est sélectionnée dans la table d'annotation
    selectedRow = app.UIAnnotationTable.Selection;
    if isempty(selectedRow) || isempty(selectedRow(1))
        return;
    end
    
    % Récupérer le nom du canal indexé sélectionné (colonne 2)
    selectedChannelName = app.UIAnnotationTable.Data{selectedRow(1), 2};
    % Trouver l'indice réel du canal dans selectedROI.display.channel
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);
    if isempty(channelIndex)
        return;
    end
    
    % Mettre à jour la couleur du canal dans selectedROI.display.rgb
    selectedROI.display.rgb(channelIndex, :) = newColor;
    
    % (Optionnel) Vous pouvez mettre à jour la table d'annotation si besoin
    % par exemple, en rappelant la fonction displayAnnotationChannels(app);
    
    % Rafraîchir l'affichage général (image, histogrammes, etc.)
    score_display(app, 'refresh');
            
        end

        % Value changed function: Transparency
        function TransparencyValueChanged(app, event)
            value = app.Transparency.Value;
             score_display(app,'refresh');
        end

        % Value changing function: Transparency
        function TransparencyValueChanging(app, event)
            changingValue = event.Value;
            app.Transparency.Value=changingValue ;
              score_display(app,'refresh');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScoreAppUIFigure = uifigure('Visible', 'off');
            app.ScoreAppUIFigure.Name = 'ScoreApp';
            app.ScoreAppUIFigure.KeyPressFcn = createCallbackFcn(app, @ScoreAppUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.ScoreAppUIFigure);
            app.FileMenu.Text = 'File';
            app.DisplayMenu = uimenu(app.ScoreAppUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.ROIsMenu = uimenu(app.DisplayMenu);
            app.ROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIsMenuSelected2, true);
            app.ROIsMenu.Text = 'ROIs';
            app.DisplaysettingsMenu = uimenu(app.DisplayMenu);
            app.DisplaysettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaysettingsMenuSelected3, true);
            app.DisplaysettingsMenu.Text = 'Display settings';
            app.IntensityquantificationMenu = uimenu(app.DisplayMenu);
            app.IntensityquantificationMenu.Text = 'Intensity quantification';
            app.AnnotationMenu = uimenu(app.DisplayMenu);
            app.AnnotationMenu.Text = 'Annotation';
            app.DatasettingsMenu = uimenu(app.DisplayMenu);
            app.DatasettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasettingsMenuSelected2, true);
            app.DatasettingsMenu.Text = 'Data settings';
            app.DatabrowserMenu = uimenu(app.DisplayMenu);
            app.DatabrowserMenu.MenuSelectedFcn = createCallbackFcn(app, @DatabrowserMenuSelected2, true);
            app.DatabrowserMenu.Text = 'Data browser';
            app.UIImageAxes = uiaxes(app.ScoreAppUIFigure);
            title(app.UIImageAxes, 'ROI #')
            xlabel(app.UIImageAxes, 'X')
            ylabel(app.UIImageAxes, 'Y')
            zlabel(app.UIImageAxes, 'Z')
            app.UIOverlayAxes = uiaxes(app.ScoreAppUIFigure);
            title(app.UIOverlayAxes, 'Title')
            xlabel(app.UIOverlayAxes, 'X')
            ylabel(app.UIOverlayAxes, 'Y')
            zlabel(app.UIOverlayAxes, 'Z')
            app.DisplaysettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.UIDisplayAxes = uiaxes(app.DisplaysettingsPanel);
            xlabel(app.UIDisplayAxes, 'X')
            ylabel(app.UIDisplayAxes, 'Y')
            zlabel(app.UIDisplayAxes, 'Z')
            app.UIChannelTable = uitable(app.DisplaysettingsPanel);
            app.UIChannelTable.ColumnName = {'Display'; 'Name'; 'Levels'; 'RGB'; 'Weight'; 'Auto'};
            app.UIChannelTable.RowName = {};
            app.UIChannelTable.SelectionChangedFcn = createCallbackFcn(app, @UIChannelTableSelectionChanged, true);
            app.ZoomLabel = uilabel(app.DisplaysettingsPanel);
            app.ZoomLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.DisplaysettingsPanel);
            app.ZoomSlider.Limits = [100 500];
            app.ZoomSlider.MajorTicks = [100 500];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.Value = 100;
            app.FrameSliderLabel = uilabel(app.DisplaysettingsPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.DisplaysettingsPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.ChannelPanel = uipanel(app.DisplaysettingsPanel);
            app.ChannelPanel.Title = 'Channel';
            app.WeightSliderLabel = uilabel(app.ChannelPanel);
            app.WeightSliderLabel.Text = 'Weight';
            app.WeightSlider = uislider(app.ChannelPanel);
            app.WeightSlider.Limits = [0 1];
            app.WeightSlider.MajorTicks = [0 0.5 1];
            app.WeightSlider.ValueChangingFcn = createCallbackFcn(app, @WeightSliderValueChanging, true);
            app.WeightSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.WeightSlider.Value = 1;
            app.ChannelColorPicker = uicolorpicker(app.ChannelPanel);
            app.ChannelColorPicker.ValueChangedFcn = createCallbackFcn(app, @ChannelColorPickerValueChanged, true);
            app.SelectedChannelLabel = uilabel(app.ChannelPanel);
            app.OverlayCheckBox = uicheckbox(app.ChannelPanel);
            app.OverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCheckBoxValueChanged, true);
            app.OverlayCheckBox.Text = 'Overlay Channels';
            app.OverlayCheckBox.Value = true;
            app.LevelsLabel = uilabel(app.ChannelPanel);
            app.LevelsLabel.Text = 'Levels';
            app.LowHighDisplaySlider = uislider(app.ChannelPanel, 'range');
            app.LowHighDisplaySlider.Limits = [0 4.8];
            app.LowHighDisplaySlider.MajorTicks = [0 1 2 3 4];
            app.LowHighDisplaySlider.MajorTickLabels = {'1', '10', '100', '1000', '10000'};
            app.LowHighDisplaySlider.ValueChangingFcn = createCallbackFcn(app, @LowHighDisplaySliderValueChanging, true);
            app.LowHighDisplaySlider.Value = [0 4.8];
            app.ResetButton = uibutton(app.DisplaysettingsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ROisPanel = uipanel(app.ScoreAppUIFigure);
            app.ROisPanel.Title = 'ROis';
            app.UIROITable = uitable(app.ROisPanel);
            app.UIROITable.ColumnName = {'Display'; 'Name'; 'Size'};
            app.UIROITable.RowName = {};
            app.UIROITable.ColumnEditable = [true false false];
            app.DataSettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DataSettingsPanel.Title = 'Data settings';
            app.UIDataTable = uitable(app.DataSettingsPanel);
            app.UIDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIDataTable.RowName = {};
            app.DataPanel = uipanel(app.ScoreAppUIFigure);
            app.DataPanel.Title = 'Data';
            app.UIDataAxes = uiaxes(app.DataPanel);
            title(app.UIDataAxes, 'Title')
            xlabel(app.UIDataAxes, 'X')
            ylabel(app.UIDataAxes, 'Y')
            zlabel(app.UIDataAxes, 'Z')
            app.AnnotationPanel = uipanel(app.ScoreAppUIFigure);
            app.AnnotationPanel.Title = 'Annotation Panel';
            app.UIAnnotationTable = uitable(app.AnnotationPanel);
            app.UIAnnotationTable.ColumnName = {'Display'; 'Name'; 'Class'};
            app.UIAnnotationTable.RowName = {};
            app.UIAnnotationTable.SelectionChangedFcn = createCallbackFcn(app, @UIAnnotationTableSelectionChanged, true);
            app.TransparencyLabel = uilabel(app.AnnotationPanel);
            app.TransparencyLabel.Text = 'Transparency';
            app.Transparency = uislider(app.AnnotationPanel);
            app.Transparency.Limits = [0 1];
            app.Transparency.ValueChangedFcn = createCallbackFcn(app, @TransparencyValueChanged, true);
            app.Transparency.ValueChangingFcn = createCallbackFcn(app, @TransparencyValueChanging, true);
            app.Transparency.Value = 0.5;
            app.Panel = uipanel(app.AnnotationPanel);
            app.Panel.Title = 'Panel';
            app.MaskcolorColorPickerLabel = uilabel(app.Panel);
            app.MaskcolorColorPickerLabel.Text = 'Mask color';
            app.MaskColorPicker = uicolorpicker(app.Panel);
            app.MaskColorPicker.ValueChangedFcn = createCallbackFcn(app, @MaskColorPickerValueChanged, true);
            app.IndexChannelLabel = uilabel(app.Panel);
            app.IndexChannelLabel.Text = 'IndexChannelLabel';
            app.IntensityQuantificationPanel = uipanel(app.ScoreAppUIFigure);
            app.IntensityQuantificationPanel.Title = 'Intensity Quantification';
            app.UIProfileAxes = uiaxes(app.IntensityQuantificationPanel);
            app.LineIntensityprofileButton = uibutton(app.IntensityQuantificationPanel, 'state');
            app.LineIntensityprofileButton.ValueChangedFcn = createCallbackFcn(app, @LineIntensityprofileButtonValueChanged2, true);
            app.LineIntensityprofileButton.Text = 'Line Intensity profile';
            app.ShapeButton = uibutton(app.IntensityQuantificationPanel, 'state');
            app.ShapeButton.ValueChangedFcn = createCallbackFcn(app, @ShapeButtonValueChanged, true);
            app.ShapeButton.Text = 'Shape intensity';
            % Show the figure after all components are created
            app.ScoreAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScoreAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScoreAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScoreAppUIFigure)
        end
    end
end